classdef HIP_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HIP3D_Calculator                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AppTab                          matlab.ui.container.Tab
        CCBY40Label                     matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        DrillingsiteBlancMesnilGeothermaldistrictheatingLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        THISAPPISDEVELOPEDUNDERLabel    matlab.ui.control.Label
        Logo_ICGC                       matlab.ui.control.Image
        Panel_8                         matlab.ui.container.Panel
        geotermiaicgccatLabel           matlab.ui.control.Label
        EmailcontactLabel               matlab.ui.control.Label
        THISAPPLICATIONLabel_7          matlab.ui.control.Label
        THISAPPLICATIONLabel_6          matlab.ui.control.Label
        Version12August2022Label        matlab.ui.control.Label
        WorkflowButton                  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        THISAPPLICATIONLabel_5          matlab.ui.control.Label
        THISAPPLICATIONLabel_4          matlab.ui.control.Label
        THISAPPLICATIONLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Logo_UAB                        matlab.ui.control.Image
        WorkflowTab                     matlab.ui.container.Tab
        Softwareusermanualv12inPDFButton  matlab.ui.control.Button
        Image3_6                        matlab.ui.control.Image
        Image11                         matlab.ui.control.Image
        GeologicalandthermalmodelsTab   matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        PetrophysicalpropertiesandHIPdistributionButton  matlab.ui.control.Button
        RUN3Button                      matlab.ui.control.StateButton
        DepthrangeselectionPanel        matlab.ui.container.Panel
        IfYesfillthefieldsbelowinmetersPanel  matlab.ui.container.Panel
        IfnoLowerdepthisselected        matlab.ui.control.Label
        Lowerdepth                      matlab.ui.control.NumericEditField
        LowerdepthmaslLabel             matlab.ui.control.Label
        Upperdepth                      matlab.ui.control.NumericEditField
        UpperdepthmaslLabel             matlab.ui.control.Label
        Depthselection                  matlab.ui.control.NumericEditField
        Depthselection1Yes0NoLabel      matlab.ui.control.Label
        RUN_1_2                         matlab.ui.control.StateButton
        HITDATA                         matlab.ui.container.Panel
        aircode                         matlab.ui.control.NumericEditField
        Indicatetheaircodeinyourmodelifitexistsifnotleave99Label  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        deltaZ                          matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        deltaY                          matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        deltaX                          matlab.ui.control.NumericEditField
        XmEditFieldLabel                matlab.ui.control.Label
        CellVolumem3Label               matlab.ui.control.Label
        lithologynameEditFiel           matlab.ui.control.EditField
        IndicatethetargetnameLabel      matlab.ui.control.Label
        SelectLithlogyCodeEditField     matlab.ui.control.NumericEditField
        SelecttargetnumericalIDLabel    matlab.ui.control.Label
        SimulationNumber                matlab.ui.control.NumericEditField
        NumberofsimulationsLabel        matlab.ui.control.Label
        Load_data                       matlab.ui.container.Panel
        ThermalModelPanel               matlab.ui.container.Panel
        SurfaceTemperature              matlab.ui.control.NumericEditField
        SurfaceTemperatureCLabel        matlab.ui.control.Label
        Thermalgradient                 matlab.ui.control.NumericEditField
        ThermalgradientCkmLabel         matlab.ui.control.Label
        Std_TemperaureEditField         matlab.ui.control.EditField
        Std_TemperaureEditFieldLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TemperatureTEditField           matlab.ui.control.EditField
        TemperatureTEditFieldLabel      matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.StateButton
        ThermalModelField               matlab.ui.control.EditField
        ThermalModelEditFieldLabel      matlab.ui.control.Label
        GeologicalModelPanel            matlab.ui.container.Panel
        PorosityEditField               matlab.ui.control.EditField
        PorosityEditFieldLabel          matlab.ui.control.Label
        Poro_yes_no                     matlab.ui.control.CheckBox
        DensityEditField                matlab.ui.control.EditField
        DensityEditFieldLabel           matlab.ui.control.Label
        Den_yes_no                      matlab.ui.control.CheckBox
        GeologyEditField                matlab.ui.control.EditField
        GeologyEditFieldLabel           matlab.ui.control.Label
        ZEditField                      matlab.ui.control.EditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        XEditFieldLabel                 matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.StateButton
        GeologicalModelField            matlab.ui.control.EditField
        GeologicalModelEditFieldLabel   matlab.ui.control.Label
        UIAxes_one                      matlab.ui.control.UIAxes
        GeothermalreservoirmapsandThistogramTab  matlab.ui.container.Tab
        Label_16                        matlab.ui.control.Label
        Image3_4                        matlab.ui.control.Image
        UIAxes_ten_bis                  matlab.ui.control.UIAxes
        UIAxes_ten                      matlab.ui.control.UIAxes
        UIAxes_nine                     matlab.ui.control.UIAxes
        UIAxes_eight                    matlab.ui.control.UIAxes
        PetrophysicalpropertiesandHIPdistributionTab  matlab.ui.container.Tab
        Image3_2                        matlab.ui.control.Image
        HRECButton                      matlab.ui.control.Button
        P10PJEditField                  matlab.ui.control.NumericEditField
        P10PJEditFieldLabel             matlab.ui.control.Label
        P50PJEditField                  matlab.ui.control.NumericEditField
        P50PJEditFieldLabel             matlab.ui.control.Label
        P90PJEditField                  matlab.ui.control.NumericEditField
        P90PJEditFieldLabel             matlab.ui.control.Label
        ExportresultsButton             matlab.ui.control.Button
        GeothermalreservoirmapsamdThistogramButton  matlab.ui.control.Button
        HIPandTmapsButton               matlab.ui.control.Button
        HeatInPlaceresultsPanel         matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        NumberofsimulationsEditField    matlab.ui.control.NumericEditField
        NumberofsimulationsEditFieldLabel  matlab.ui.control.Label
        P10PJ                           matlab.ui.control.NumericEditField
        P10PJLabel                      matlab.ui.control.Label
        P50PJ                           matlab.ui.control.NumericEditField
        P50PJLabel                      matlab.ui.control.Label
        P90PJ                           matlab.ui.control.NumericEditField
        P90PJLabel                      matlab.ui.control.Label
        GeothermaltargetEditField       matlab.ui.control.EditField
        GeothermaltargetEditFieldLabel  matlab.ui.control.Label
        RUN4Button                      matlab.ui.control.StateButton
        PetrophysicalpropertiesandotherparametersPanel  matlab.ui.container.Panel
        ConversionEfficiency            matlab.ui.control.NumericEditField
        ConversionEfficiencyEditFieldLabel  matlab.ui.control.Label
        Den_no_used                     matlab.ui.control.EditField
        RockDensitykgm3Label            matlab.ui.control.Label
        Live_Time                       matlab.ui.control.NumericEditField
        MeanPlantLivetimeyearsLabel     matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        Upp_RF                          matlab.ui.control.NumericEditField
        UppEditField_2Label_4           matlab.ui.control.Label
        Max_RF                          matlab.ui.control.NumericEditField
        MaxEditField_2Label_4           matlab.ui.control.Label
        Low_RF                          matlab.ui.control.NumericEditField
        LowEditField_2Label_4           matlab.ui.control.Label
        Check_T_RF                      matlab.ui.control.CheckBox
        Panel_6                         matlab.ui.container.Panel
        Mean_RF                         matlab.ui.control.NumericEditField
        MeanLabel_5                     matlab.ui.control.Label
        Std_RF                          matlab.ui.control.NumericEditField
        StdLabel_5                      matlab.ui.control.Label
        Check_N_RF                      matlab.ui.control.CheckBox
        RecoveryFactorLabel             matlab.ui.control.Label
        PlantFactor                     matlab.ui.control.NumericEditField
        PlantFactorLabel                matlab.ui.control.Label
        Poro_no_used                    matlab.ui.control.EditField
        RockspecificheatcapacitykJkgCLabel  matlab.ui.control.Label
        FluidspecificheatcapacitykJkgCLabel  matlab.ui.control.Label
        FluidDensitykgm3Label           matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        Max_Lito_Density                matlab.ui.control.NumericEditField
        MaxEditField_2Label_5           matlab.ui.control.Label
        Upp_Lito_Density                matlab.ui.control.NumericEditField
        UppEditField_2Label_5           matlab.ui.control.Label
        Low_Lito_Density                matlab.ui.control.NumericEditField
        LowEditField_2Label_5           matlab.ui.control.Label
        Check_T_LDen                    matlab.ui.control.CheckBox
        Check_T_Poro                    matlab.ui.control.CheckBox
        Check_T_FDen                    matlab.ui.control.CheckBox
        Check_T_FVol                    matlab.ui.control.CheckBox
        Check_T_LVol                    matlab.ui.control.CheckBox
        Low_Lito_Volumetric             matlab.ui.control.NumericEditField
        LowEditField_2Label_3           matlab.ui.control.Label
        Upp_Lito_Volumetric             matlab.ui.control.NumericEditField
        UppEditField_2Label_3           matlab.ui.control.Label
        Max_Lito_Volumetric             matlab.ui.control.NumericEditField
        MaxEditField_2Label_3           matlab.ui.control.Label
        Low_Fluid_Volumetric            matlab.ui.control.NumericEditField
        LowEditField_2Label_2           matlab.ui.control.Label
        Upp_Fluid_Volumetric            matlab.ui.control.NumericEditField
        UppEditField_2Label_2           matlab.ui.control.Label
        Max_Fluid_Volumetric            matlab.ui.control.NumericEditField
        MaxEditField_2Label_2           matlab.ui.control.Label
        Low_Fluid_Density               matlab.ui.control.NumericEditField
        LowEditField_2Label             matlab.ui.control.Label
        Max_Fluid_Density               matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        Upp_Fluid_Density               matlab.ui.control.NumericEditField
        UppEditField_2Label             matlab.ui.control.Label
        Max_Porosity                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        Upp_Porosity                    matlab.ui.control.NumericEditField
        UppEditFieldLabel               matlab.ui.control.Label
        Low_Porosity                    matlab.ui.control.NumericEditField
        LowEditFieldLabel               matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Check_N_LDen                    matlab.ui.control.CheckBox
        Mean_Lito_Density               matlab.ui.control.NumericEditField
        MeanLabel_6                     matlab.ui.control.Label
        Std_Lito_Density                matlab.ui.control.NumericEditField
        StdLabel_6                      matlab.ui.control.Label
        Check_N_LVol                    matlab.ui.control.CheckBox
        Check_N_FVol                    matlab.ui.control.CheckBox
        Check_N_FDen                    matlab.ui.control.CheckBox
        Check_N_Poro                    matlab.ui.control.CheckBox
        Std_Litovolumetricheatcapacity  matlab.ui.control.NumericEditField
        StdLabel_4                      matlab.ui.control.Label
        Mean_Litovolumetricheatcapacity  matlab.ui.control.NumericEditField
        MeanLabel_4                     matlab.ui.control.Label
        Std_Fluidvolumetricheatcapacity  matlab.ui.control.NumericEditField
        StdLabel_3                      matlab.ui.control.Label
        Mean_Fluidvolumetricheatcapacity  matlab.ui.control.NumericEditField
        MeanLabel_3                     matlab.ui.control.Label
        Std_Fluid_Density               matlab.ui.control.NumericEditField
        StdLabel_2                      matlab.ui.control.Label
        Mean_Fluid_Density              matlab.ui.control.NumericEditField
        MeanLabel_2                     matlab.ui.control.Label
        Std_Porosity                    matlab.ui.control.NumericEditField
        StdLabel                        matlab.ui.control.Label
        Mean_Porosity                   matlab.ui.control.NumericEditField
        MeanLabel                       matlab.ui.control.Label
        PDFTriangulardistributionLabel  matlab.ui.control.Label
        PDFNormaldistributionLabel      matlab.ui.control.Label
        PorosityLabel                   matlab.ui.control.Label
        ReinjectionTemp                 matlab.ui.control.NumericEditField
        ReinjectionTemperatureCLabel    matlab.ui.control.Label
        UIAxes_three                    matlab.ui.control.UIAxes
        UIAxes_two                      matlab.ui.control.UIAxes
        HIPandTmapsTab                  matlab.ui.container.Tab
        Label_15                        matlab.ui.control.Label
        Image3_3                        matlab.ui.control.Image
        UIAxes_seven                    matlab.ui.control.UIAxes
        UIAxes_six                      matlab.ui.control.UIAxes
        UIAxes_five                     matlab.ui.control.UIAxes
        UIAxes_four                     matlab.ui.control.UIAxes
        HrecmapsTab                     matlab.ui.container.Tab
        Label_17                        matlab.ui.control.Label
        Image3_5                        matlab.ui.control.Image
        UIAxes_thirteen                 matlab.ui.control.UIAxes
        UIAxes_twelve                   matlab.ui.control.UIAxes
        UIAxes_eleven                   matlab.ui.control.UIAxes
        ExportresultsTab                matlab.ui.container.Tab
        Image10                         matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        PirisGHermsIGrieraAGomezRivasEColomerMLabel  matlab.ui.control.Label
        Version12August2022Label_2      matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        ExportfilesPanel                matlab.ui.container.Panel
        File22DModelPanel               matlab.ui.container.Panel
        Exportedvariables2DmodelLabel   matlab.ui.control.Label
        FILE2XmYmP10PJP50PJP90PJFOR2DVISUALIZATIONLabel_2  matlab.ui.control.Label
        NAMEFile2                       matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        BROWSEFile2                     matlab.ui.control.Button
        DirectoryFile2                  matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        RUN5Button                      matlab.ui.control.Button
        ReportPanel                     matlab.ui.container.Panel
        BROWSEReport                    matlab.ui.control.Button
        NAMEReport                      matlab.ui.control.EditField
        FilenameLabel_3                 matlab.ui.control.Label
        DirectoryReport                 matlab.ui.control.EditField
        DirectoryEditFieldLabel_2       matlab.ui.control.Label
        File13DModelPanel               matlab.ui.container.Panel
        Exportedvariables3DmodelLabel   matlab.ui.control.Label
        FILE1XmYmZmP10PJP50PJP90PJMEAN_HIPPJSTD_HIPPJTCStd_TCLabel_2  matlab.ui.control.Label
        NAMEFile1                       matlab.ui.control.EditField
        FilenameLabel_2                 matlab.ui.control.Label
        BROWSEFile1                     matlab.ui.control.Button
        DirectoryFile1                  matlab.ui.control.EditField
        DirectoryEditField_2Label       matlab.ui.control.Label
    end

    
    properties (Access = private)
        dades_netes % Matrix with all the values
        col_X %Column of X values
        col_Y %Column of Y values
        col_Z % Column of depth values
        col_temp %Temperature column
        col_std_temp %Temperature standard desviation column
        col_den %Density column
        col_lito %Lithology column
        col_p %Porosity column if used
        n %Simulation number
        v %Cell volume
        A %Rows for the HIP matrix
        B %Columns for the HIP matrix
        a %Lithology name
        Z_selec % Upper depth
        Z_selec_b % Lower depth
        min_Z %Maximum depth
        max_x %Maximum X coor
        max_y %Maximum Y coor
        min_x %Minimum X coor
        min_y %Minimum Y coor
        HIT_LITO %File 1
        HIP_Hrec_2D %File 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function P_B_START(app, event)
            app.TabGroup.SelectedTab = app.GeologicalandthermalmodelsTab;
        end

        % Button pushed function: WorkflowButton
        function P_B_INTRODUCTION(app, event)
            app.TabGroup.SelectedTab = app.WorkflowTab;
        end

        % Button pushed function: Softwareusermanualv12inPDFButton
        function P_B_MANUAL(app, event)
        winopen('3DHIP_Calculator_Software_User_Manual_v1.2.pdf');    
            
        end

        % Callback function
        function P_B_START_2(app, event)
            app.TabGroup.SelectedTab = app.GeologicalandthermalmodelsTab;
        end

        % Value changed function: BrowseButton_2
        function browse_ther(app, event)
            [DFileName, DFilePath] = uigetfile();
            DePath = fullfile(DFilePath,DFileName);
            app.ThermalModelField.Value = DePath;
            figure(app.HIP3D_Calculator);
        end

        % Value changed function: BrowseButton
        function browse_geo(app, event)
            [AFileName, AFilePath] = uigetfile();
            AePath = fullfile(AFilePath,AFileName);
            app.GeologicalModelField.Value = AePath;
            figure(app.HIP3D_Calculator);
        end

        % Value changed function: RUN_1_2
        function RUN12Button(app, event)
            
            %%%%%%%%%% INITIATE THE WAIT BAR FIGURE %%%%%%%%5
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
            'Message','Opening the application');
            pause(.5)
            %%%%%%%%%%
            
            AePath = app.GeologicalModelField.Value;
            %Loading files densi=density
            densi=importdata(AePath);
            identificador_columnes_densi=densi.colheaders;
            densi_dades=densi.data;
                       
                       
            %Erase all the columns except  "X" "Y" "Z" "LITHOLOGY" "DENSITY"
            %"POROSITY"
            
            for i = 1:length(identificador_columnes_densi)
        
            s1=identificador_columnes_densi(i);
            s2=app.XEditField.Value;
            s3=app.YEditField.Value;
            s4=app.ZEditField.Value;
            s5=app.GeologyEditField.Value;
            
            if app.Den_yes_no.Value==1
            s6=app.DensityEditField.Value;
            td = strcmp(s1,s6);
            if td ==1  
            app.col_den=i;
            end
            end
            
            tX = strcmp(s1,s2);
            tY = strcmp(s1,s3);
            tZ = strcmp(s1,s4);
            tl = strcmp(s1,s5);


            if tX ==1  
            app.col_X=i;
            end
    
            if tY ==1  
            app.col_Y=i;
            end
    
            if tZ ==1  
            app.col_Z=i;
            end
    
            if tl ==1
            app.col_lito=i;
            end
            
            
            if app.Poro_yes_no.Value == 1
            s7 = app.PorosityEditField.Value;
            tp=strcmp(s1,s7);
            if tp ==1
            app.col_p=i;
            end
            end
            
            end
    
            ax=zeros(1,i);
            for i=1:length(identificador_columnes_densi)
            ax(i)=i;
            end
    
            ax(ax==app.col_X)=[];
            ax(ax==app.col_Y)=[];
            ax(ax==app.col_Z)=[];
            ax(ax==app.col_lito)=[];
            if app.Den_yes_no.Value == 1
            ax(ax==app.col_den)=[];
            end
            if app.Poro_yes_no.Value == 1
            ax(ax==app.col_p)=[];
            end
    
            %%Erase the extra columns from the geological model
            densi_dades (:,ax)=[];
            %Erase the extra headers from the geological model
            identificador_columnes_densi (:,ax)=[];
            
            %%%%%%%%%% WAIT BAR FIGURE %%%%%%%
            d.Value = .33; 
            d.Message = 'Loading your data';
            pause(1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            if app.Thermalgradient.Value == 0
                
            
            DePath= app.ThermalModelField.Value;
            %Loading files mat=temperature values
            mat=importdata(DePath);
            ide_col=mat.colheaders;
            app.dades_netes=mat.data;
            
            %Erase all the columns except "Mean_Temperature" and "Stddev_Temperature" 

            for i = 1:length(ide_col)
    
                s1=ide_col(i);
                s6=app.TemperatureTEditField.Value;
                s7=app.Std_TemperaureEditField.Value;
    
                tt = strcmp(s1,s6);
                tst = strcmp(s1,s7);
           
                if tt ==1
                app.col_temp=i;
                end
    
                if tst ==1  
                app.col_std_temp=i;
                end
            end

            ay=zeros(1,i);

            for i=1:length(ide_col)
            ay(i)=i;
            end

            ay(ay==app.col_temp)=[];
            ay(ay==app.col_std_temp)=[];

            %Erase the extra columns from the thermal model
            app.dades_netes(:,ay)=[];
            %Erase the extra headers from the thermal model
            ide_col(:,ay)=[];
        
            else
            
            %Calculate the local depth to apply the Thermal gradient. To dat, first, we
            %need to eliminate the air (if there is).
                        
            densi_dades((densi_dades(:,app.col_lito)==app.aircode.Value),:)=[];
            [M,N]=size (densi_dades);
            
            %Now a bucle is created to identify for each X Y pair the
            %highest Z value and then calculate the local depth.
            
            [BOUND_X_Y,nn,uu]=unique(densi_dades(:,app.col_X:app.col_Y),'Rows');
            A_D=[];
            
            for k=1:max(uu)
            rr=densi_dades(uu==k,:);
            ma=max(rr(:,app.col_Z));
            rr(:,N+1)=rr(:,app.col_Z)-ma;
            A_D=[A_D;rr];
            end
            
            %Temperature calculation in  ºC
            A_D(:,N+2)=abs(A_D(:,N+1)*app.Thermalgradient.Value*0.001)+app.SurfaceTemperature.Value;
                                   
                       
            %Building the thermal matrix, we decide use a 0 standard deviation
            %when a temperature gradient is introduced. This is done to do
            %not generate problems with the code respect to intrduce a
            %thermal model with a mean temperature and the associated
            %standard deviation. We also need to replace the old
            %densi_dades because now the cells order is different.
            
            app.dades_netes(:,1)=A_D(:,N+2);
            app.dades_netes(:,2)=0;
            
            A_D(:,[N+1 N+2])=[];
            
            densi_dades=A_D;
            
            %app.dades_netes(:,1)=abs(densi_dades(:,app.col_Z)*app.Thermalgradient.Value*0.001);
            
            ide_col={app.TemperatureTEditField.Value app.Std_TemperaureEditField.Value};
            
            end
        
            %%%%%%%%%%%%%%% WAIT BAR FIGURE %%%%%%
            d.Value = .66; 
            d.Message = 'Loading your data';
            pause(1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Join the geological and thermal model into the dades_netes matrix
        % Join the geological and thermal headers into the ide_col matrix
        ide_col =[identificador_columnes_densi,ide_col];
        app.dades_netes=[densi_dades,app.dades_netes];
        [app.A,app.B]=size (app.dades_netes);  
        
            
        %Now we identify the new column position for the different
        %variables
        for i = 1:length(ide_col)
    
        s1=ide_col(i);
        s2=app.XEditField.Value;
        s3=app.YEditField.Value;
        s4=app.ZEditField.Value;
        s5=app.GeologyEditField.Value;
        s6=app.TemperatureTEditField.Value;
        s7=app.Std_TemperaureEditField.Value;


        if app.Den_yes_no.Value == 1
        s8=app.DensityEditField.Value;
        td = strcmp(s1,s8);
        if td ==1
        app.col_den=i;
        end
        end
    
        tX = strcmp(s1,s2);
        tY = strcmp(s1,s3);
        tZ = strcmp(s1,s4);
        tl = strcmp(s1,s5);
        tt = strcmp(s1,s6);
        tst = strcmp(s1,s7);
        
    
        if tX ==1  
        app.col_X=i;
        end
    
        if tY ==1  
        app.col_Y=i;
        end
    
        if tZ ==1  
        app.col_Z=i;
        end
                
        if tl ==1
        app.col_lito=i;
        end
    
        if tt ==1 
        app.col_temp=i;
        end
    
        if tst ==1
        app.col_std_temp=i;
        end
    
        
        if app.Poro_yes_no.Value == 1
        s9 = app.PorosityEditField.Value;
        tp=strcmp(s1,s9);
        if tp ==1
        app.col_p=i;
        end
        end


        end 
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%% WAIT BAR FIGURE %%%%%%%%%%
        % Finish calculations
        d.Value = 1;
        d.Message = 'Finishing';
        pause(1)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    
        %Simulation number for HIP calculation
        app.n=app.SimulationNumber.Value;
        %Cell volume
        app.v=app.deltaX.Value*app.deltaY.Value*app.deltaZ.Value;
        
        lit_selec= app.SelectLithlogyCodeEditField.Value;  
        app.a=app.lithologynameEditFiel.Value; 
        app.dades_netes((app.dades_netes(:,app.col_lito)~=lit_selec),:)=[];
        [app.A,app.B]=size (app.dades_netes);

                        
        %Plot the selected lithology temperature distribution with depth
        
        scatter(app.UIAxes_one,app.dades_netes(:,app.col_temp),app.dades_netes(:,app.col_Z),'o','blue');
        hold(app.UIAxes_one,'on');
        ylabel(app.UIAxes_one,'Altitude [m a.s.l.]',"FontSize",12);
        xlabel(app.UIAxes_one,'Temperature [ºC]',"FontSize",12);
        name = 'Distribution of Temperature at Depth of';
        extra = [name ' ' app.a];
        title(app.UIAxes_one,extra,"FontSize",14);
        
        %%%%%%%%%%%%%%%% WAIT BAR FIGURE %%%%%%%%%%%
        % Close dialog box
        close(d);
        close(f);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %app.Prova_1.Value=app.B;
        %app.Prova_2.Value=app.col_p;
        
        %Notifing that the code will not use the porosity values
        if app.Poro_yes_no.Value == 1
            app.Poro_no_used.Visible = 'on';
            app.Poro_no_used.Value ='NOT USED';
        end
        
        %Notifing that the code will not use the density values
        if app.Den_yes_no.Value == 1
            app.Den_no_used.Visible = 'on';
            app.Den_no_used.Value ='NOT USED';
        end
        
            
        end

        % Value changed function: RUN3Button
        function RUN3CutDepth(app, event)
            
            max_T=max(app.dades_netes(:,app.col_temp));
            max_T=max_T+10;
            app.min_Z=min(app.dades_netes(:,app.col_Z));
            
            if app.Depthselection.Value ==1 
              z_selec = app.Upperdepth.Value;
              app.dades_netes((app.dades_netes(:,app.col_Z)>z_selec),:)=[];
              [app.A,app.B]=size (app.dades_netes);
              
              P1=[0 app.Upperdepth.Value];
              P2=[max_T app.Upperdepth.Value];
              P3=[0 app.min_Z];
              P4=[max_T app.min_Z];
              
              
              if app.Lowerdepth.Value ~= 0   
              z_selec_b = app.Lowerdepth.Value;
              app.dades_netes((app.dades_netes(:,app.col_Z)<z_selec_b),:)=[];
              [app.A,app.B]=size (app.dades_netes);
              P3=[0 app.Lowerdepth.Value];
              P4=[max_T app.Lowerdepth.Value];
              end
              
              plot(app.UIAxes_one,[P1(1) P2(1)],[P1(2) P2(2)],'r','LineWidth',2);
              hold(app.UIAxes_one,'on');
              plot(app.UIAxes_one,[P3(1) P4(1)],[P3(2) P4(2)],'r','LineWidth',2);
              
            end
            
           if app.Depthselection.Value ==0
               
              P1=[0 max(app.dades_netes(:,app.col_Z))];
              P2=[max_T max(app.dades_netes(:,app.col_Z))];
              P3=[0 app.min_Z];
              P4=[max_T app.min_Z]; 
               
              plot(app.UIAxes_one,[P1(1) P2(1)],[P1(2) P2(2)],'r','LineWidth',2);
              hold(app.UIAxes_one,'on');
              plot(app.UIAxes_one,[P3(1) P4(1)],[P3(2) P4(2)],'r','LineWidth',2);
               
           end
            
            
            nbins=30;
            % UNIT TEMPERATURE DISTRIBUTION

            max_t=max(app.dades_netes(:,app.col_temp));
            min_t=min(app.dades_netes(:,app.col_temp));
            
            xbins_t=linspace(min_t,max_t,nbins);
            dist_t=hist(app.dades_netes(:,app.col_temp),xbins_t);
            
            %PLOT THE UNIT TEMPERATURE DISTRIBUTION
                     
            histogram(app.UIAxes_ten_bis,app.dades_netes(:,app.col_temp),xbins_t);
            
            xlabel(app.UIAxes_ten_bis,'Temperature (ºC)');
            ylabel(app.UIAxes_ten_bis,'Frequency');
            
            name = 'Temperature distribution of';
            extra = [name ' ' app.a];
            
            if app.Depthselection.Value == 1
            app.Z_selec=num2str(app.Upperdepth.Value,'%4.2i');    
            extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
            end
            if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
            app.Z_selec=num2str(app.Upperdepth.Value,'%4.2i');
            app.Z_selec_b=num2str(app.Lowerdepth.Value,'%4.2i');    
            extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
            end
            title (app.UIAxes_ten_bis,extra);
            
        if app.A ~= 1         
       %GENERATE THE 2D MESH FOR THE PLOTS
       
       app.min_x=min(app.dades_netes(:,app.col_X));
       app.max_x=max(app.dades_netes(:,app.col_X));
       app.min_y=min(app.dades_netes(:,app.col_Y));
       app.max_y=max(app.dades_netes(:,app.col_Y));
       
       [x_plot,y_plot]=meshgrid(app.min_x:app.deltaX.Value:app.max_x, app.min_y:app.deltaY.Value:app.max_y);
                  
       [GG,PP]=size(x_plot);
       
           
       %%THICKNESS    
       
           THICK=[app.dades_netes(:,app.col_X), app.dades_netes(:,app.col_Y), app.dades_netes(:,app.col_Z)];
           
           [AT,BT]=size(THICK);

           [BOUND_X_Y,nn,uu]=unique(THICK(:,1:2),'Rows');

           A_D=[];

            for k=1:max(uu)
                rr=THICK(uu==k,:);
                ma=max(rr(:,3));
                mi=min(rr(:,3));
                rr(:,BT)=ma-mi;
                if ma==mi
                rr(:,BT)=app.deltaZ.Value;    
                end
                A_D=[A_D;rr];
                
            end
            
            [C,ia,ic] = unique(A_D(:,1:2),'rows');
            THICK = A_D(ia,:);
            [AT,BT]=size(THICK);
                        
            THICK_M=zeros(GG,PP);
            
            for i = 1 : AT
                    
                 [fil1,col1]=min(abs(x_plot(1,:)-THICK(i,1)));
                 [fil2,col2]=min(abs(y_plot(:,1)-THICK(i,2)));
                 
                 THICK_M(col2,col1)=THICK(i,3);
                  
            end
             
            
            %PLOT THE THICKNESS IN 2D
                THICK_M(THICK_M==0)=NaN;
                s=pcolor(app.UIAxes_ten,x_plot,y_plot,THICK_M);
                s.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_ten);
                %caxis([0 14]);
                xlabel(app.UIAxes_ten,'X [m]');
                ylabel(app.UIAxes_ten,'Y [m]');
                c.Label.String = 'Vertical thickness [m]';
                c.AxisLocation ='in';
                                
                name = 'Vertical thickness of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_ten,extra);
                
        %%TOP
            
            TOP=[app.dades_netes(:,app.col_X), app.dades_netes(:,app.col_Y), app.dades_netes(:,app.col_Z)];
            
            [AT,BT]=size(TOP);

           [BOUND_X_Y,nn,uu]=unique(TOP(:,1:2),'Rows');

           A_D=[];

            for k=1:max(uu)
                rr=TOP(uu==k,:);
                ma=max(rr(:,3));
                rr(:,BT)=ma;
                A_D=[A_D;rr];
            end
            
            [C,ia,ic] = unique(A_D(:,1:2),'rows');
            TOP = A_D(ia,:);
            [AT,BT]=size(TOP);
                        
            TOP_M=zeros(GG,PP);
            
            for i = 1 : AT
                    
                 [fil1,col1]=min(abs(x_plot(1,:)-TOP(i,1)));
                 [fil2,col2]=min(abs(y_plot(:,1)-TOP(i,2)));
                 
                 TOP_M(col2,col1)=TOP(i,3);
                  
            end
            
            %PLOT THE TOP IN 2D
                TOP_M(TOP_M==0)=NaN;
                q=pcolor(app.UIAxes_eight,x_plot,y_plot,TOP_M);
                q.EdgeColor = 'none';
                colormap(app.UIAxes_eight,'jet');
                %s.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_eight);
                xlabel(app.UIAxes_eight,'X [m]');
                ylabel(app.UIAxes_eight,'Y [m]');
                c.Label.String = 'Depth [m a.s.l.]';
                c.AxisLocation ='in';
                                
                name = 'Top of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_eight,extra);
                
           
       %%BASE
            
            BASE=[app.dades_netes(:,app.col_X), app.dades_netes(:,app.col_Y), app.dades_netes(:,app.col_Z)];
            
            [AB,BB]=size(BASE);

           [BOUND_X_Y,nn,uu]=unique(BASE(:,1:2),'Rows');

           A_D=[];

            for k=1:max(uu)
                rr=BASE(uu==k,:);
                mi=min(rr(:,3));
                rr(:,BB)=mi;
                A_D=[A_D;rr];
            end
            
            [C,ia,ic] = unique(A_D(:,1:2),'rows');
            BASE = A_D(ia,:);
            [AB,BB]=size(BASE);
                        
            BASE_M=zeros(GG,PP);
            
            for i = 1 : AB
                    
                 [fil1,col1]=min(abs(x_plot(1,:)-BASE(i,1)));
                 [fil2,col2]=min(abs(y_plot(:,1)-BASE(i,2)));
                 
                 BASE_M(col2,col1)=BASE(i,3);
                  
            end
            
            %PLOT THE BASE IN 2D
                BASE_M(BASE_M==0)=NaN;
                q=pcolor(app.UIAxes_nine,x_plot,y_plot,BASE_M);
                q.EdgeColor = 'none';
                colormap(app.UIAxes_nine,'jet');
                %s.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_nine);
                xlabel(app.UIAxes_nine,'X [m]');
                ylabel(app.UIAxes_nine,'Y [m]');
                c.Label.String = 'Depth [m a.s.l.]';
                c.AxisLocation ='in';
                                
                name = 'Base of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_nine,extra);
        else
        end
                        
        end

        % Button pushed function: 
        % PetrophysicalpropertiesandHIPdistributionButton
        function P_B_LITO_DATA(app, event)
            app.TabGroup.SelectedTab = app.PetrophysicalpropertiesandHIPdistributionTab;
        end

        % Value changed function: RUN4Button
        function RUN45HIP(app, event)
            
           
            %%%%%%%%%% INITIATE THE WAIT BAR FIGURE %%%%%%%%5
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
            'Message','Loading your data');
            pause(.5)
            %%%%%%%%%%


            %Buiding the normal or triangular distributions for the different parameters
            %Porosity
            if app.Check_N_Poro.Value == 1
            poro_1=normrnd(app.Mean_Porosity.Value,app.Std_Porosity.Value,app.n,1);
            end
            if app.Check_T_Poro.Value == 1
            LP=app.Low_Porosity.Value;
            MP=app.Max_Porosity.Value;
            UP=app.Upp_Porosity.Value;
            pd = makedist ('Triangular',LP,MP,UP);
            poro_1 = random (pd,app.n,1);
            end
            %Fluid density (Kg/m3)
            if app.Check_N_FDen.Value == 1
            den_f_1=normrnd(app.Mean_Fluid_Density.Value,app.Std_Fluid_Density.Value,app.n,1);
            end
            if app.Check_T_FDen.Value == 1
            LFD=app.Low_Fluid_Density.Value;
            MFD=app.Max_Fluid_Density.Value;
            UFD=app.Upp_Fluid_Density.Value;
            pd = makedist ('Triangular',LFD,MFD,UFD);
            den_f_1 = random (pd,app.n,1);
%             app.CEditField.Value = den_f_1(1,1);
%             app.AEditField.Value = app.Max_Fluid_Density.Value;
%             app.BEditField.Value = app.Low_Fluid_Density.Value;
            end
            %Fluid specific heat capacity (KJ/Kg ºC)
            if app.Check_N_FVol.Value == 1
            ccalo_f_1=normrnd(app.Mean_Fluidvolumetricheatcapacity.Value,app.Std_Fluidvolumetricheatcapacity.Value,app.n,1);
            end
            if app.Check_T_FVol.Value == 1
            LVF=app.Low_Fluid_Volumetric.Value;
            MVF=app.Max_Fluid_Volumetric.Value;
            UMF=app.Upp_Fluid_Volumetric.Value;
            pd = makedist ('Triangular',LVF,MVF,UMF);
            ccalo_f_1 = random (pd,app.n,1);
            end
            %Rock density (Kg/m3)
            if app.Check_N_LDen.Value == 1
            den_l_1=normrnd(app.Mean_Lito_Density.Value,app.Std_Lito_Density.Value,app.n,1);
            end
            if app.Check_T_LDen.Value == 1
            LLD=app.Low_Lito_Density.Value;
            MLD=app.Max_Lito_Density.Value;
            ULD=app.Upp_Lito_Density.Value;
            pd = makedist ('Triangular',LLD,MLD,ULD);
            den_l_1 = random (pd,app.n,1);
            end                        
            %Rock specific heat capacity (KJ/Kg ºC)
            if app.Check_N_LVol.Value == 1
            ccalo_l_1=normrnd(app.Mean_Litovolumetricheatcapacity.Value,app.Std_Litovolumetricheatcapacity.Value,app.n,1);
            end
            if app.Check_T_LVol.Value == 1
            LVL=app.Low_Lito_Volumetric.Value;
            MVL=app.Max_Lito_Volumetric.Value;
            UVL=app.Upp_Lito_Volumetric.Value;
            pd = makedist ('Triangular',LVL,MVL,UVL);
            ccalo_l_1 = random (pd,app.n,1);
            end
            %Reinjection Temperature
            Tr=app.ReinjectionTemp.Value;
            %Plant Factor
            Pf=app.PlantFactor.Value;
            %Recovery factor
            if app.Check_N_RF.Value == 1
            R_1=normrnd(app.Mean_RF.Value,app.Std_RF.Value,app.n,1);
            end
            if app.Check_T_RF.Value == 1
            LRF=app.Low_RF.Value;
            MRF=app.Max_RF.Value;
            URF=app.Upp_RF.Value;
            pd = makedist ('Triangular',LRF,MRF,URF);
            R_1 = random (pd,app.n,1);
            end
            %LIVE TIME (S)
            Live_time=app.Live_Time.Value*365*86400;
            %Conversion efficiency
            Ce=app.ConversionEfficiency.Value;
            
            %%%%%%%%%% WAIT BAR FIGURE %%%%%%%
            d.Value = .33; 
            d.Message = 'Calculating HIP & Hrec';
            pause(1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %HIP CALCULATIONS & Recoverable heat energy (Hrec)
            HIT=zeros(app.A,app.n); 
            Hrec=zeros(app.A,app.n);

                            
            for i=1:app.A     
            
            %The cell HIP (KJ) will be calculated if the cell temperature i higher or equal to the reinjection temperature plus 5 degrees alse the HIP will be 0
            %The Hrec (KW) will be calculated from the HIP value
            if app.dades_netes(i,app.col_temp)>=Tr+5%std_Tr
    
            TR=normrnd(app.dades_netes(i,app.col_temp),app.dades_netes(i,app.col_std_temp),app.n,1);

            if app.Poro_yes_no.Value==0 && app.Den_yes_no.Value ==1 %A THe 1000 is to convert g/cm3 to kg/m3
            HIT(i,:)=app.v*((poro_1.*den_f_1.*ccalo_f_1)+(1-poro_1).*app.dades_netes(i,app.col_den).*ccalo_l_1.*1000).*(TR-Tr);
            Hrec(i,:)=(HIT(i,:).*(Ce).*R_1')/(Live_time*Pf);
            end
            
            if app.Poro_yes_no.Value==1 && app.Den_yes_no.Value == 1 %B THe 1000 is to convert g/cm3 to kg/m3
            HIT(i,:)=app.v*((app.dades_netes(i,app.col_p).*den_f_1.*ccalo_f_1)+(1-app.dades_netes(i,app.col_p)).*app.dades_netes(i,app.col_den).*ccalo_l_1.*1000).*(TR-Tr);
            Hrec(i,:)=(HIT(i,:).*(Ce).*R_1')/(Live_time*Pf);
            end
            
            if app.Poro_yes_no.Value==1 && app.Den_yes_no.Value == 0 %C
            HIT(i,:)=app.v*((app.dades_netes(i,app.col_p).*den_f_1.*ccalo_f_1)+(1-app.dades_netes(i,app.col_p)).*den_l_1.*ccalo_l_1).*(TR-Tr);
            Hrec(i,:)=(HIT(i,:).*(Ce).*R_1')/(Live_time*Pf);
            end
            
            if app.Poro_yes_no.Value==0 && app.Den_yes_no.Value ==0 %D
            HIT(i,:)=app.v*((poro_1.*den_f_1.*ccalo_f_1)+(1-poro_1).*den_l_1.*ccalo_l_1).*(TR-Tr);
            Hrec(i,:)=(HIT(i,:).*(Ce).*R_1')/(Live_time*Pf);
            end
            
            else 
    
            HIT (i,:)=0;
            Hrec (i,:)=0;

            end
            end
            
            %Create the distributions for the HIP n calculations for each
            %cell
            
            %Interval number for histogram representation
            nbins=30;

            %Create the distribution interval
            ma=max(HIT,[],2);
            mi=min(HIT,[],2);
            xbins=zeros(app.A,nbins);
            
            ma_Hrec=max(Hrec,[],2); %mod
            mi_Hrec=min(Hrec,[],2); %mod
            xbins_Hrec=zeros(app.A,nbins); %mod

            for i=1:app.A
            xbins(i,:)=linspace(mi(i),ma(i),nbins);
            xbins_Hrec(i,:)=linspace(mi_Hrec(i),ma_Hrec(i),nbins); %mod
            end
            
            %Create the distributions for all the cells
            dist=zeros(app.A,nbins);
            ndist=zeros(app.A,nbins);
            cdist=zeros(app.A,nbins);
            
            dist_Hrec=zeros(app.A,nbins);%MOD
            ndist_Hrec=zeros(app.A,nbins);%MOD
            cdist_Hrec=zeros(app.A,nbins);%MOD

            for i =1:app.A
            dist(i,:)=hist(HIT(i,:),xbins(i,:));
            ndist(i,:)=dist(i,:)/sum(dist(i,:));
            cdist(i,:)=cumsum(ndist(i,:));
            
            dist_Hrec(i,:)=hist(Hrec(i,:),xbins_Hrec(i,:));%MOD
            ndist_Hrec(i,:)=dist_Hrec(i,:)/sum(dist_Hrec(i,:));%MOD
            cdist_Hrec(i,:)=cumsum(ndist_Hrec(i,:));%MOD
            end

            %The HIP matrix includes n HIP results for each cell (or
            %lithology voxet) now we sum all the cells obtaining n HIP for the entire
            %unit. If the user provides an one cell  modell the number of
            %files  (A) will be 1 and this sum won't be done.
            
            if app.A==1
            HIT_U=HIT;
            ma_U=ma;
            mi_U=mi;
            
            xbins_U=xbins;
            dist_U=dist;
            ndist_U=ndist;
            cdist_U=cdist;
            else
            HIT_U=sum(HIT);
            ma_U=max(HIT_U);
            mi_U=min(HIT_U);
   
            xbins_U=linspace(mi_U,ma_U,nbins);
            dist_U=hist(HIT_U,xbins_U);
            ndist_U=dist_U/sum(dist_U);
            cdist_U=cumsum(ndist_U);
            end
            
            %And now we obtain the HIP_10, HIP_50 and HIP_for this
            %lithological unit
            
            [minimo1,indice1]=min(abs(cdist_U-0.9));
            [minimo2,indice2]=min(abs(cdist_U-0.5));
            [minimo3,indice3]=min(abs(cdist_U-0.1));

            p10_l=xbins_U(indice1);
            p50_l=xbins_U(indice2);
            p90_l=xbins_U(indice3);
    
            % Transformation from KJ to PJ
            p10_l=p10_l/1e12;
            p50_l=p50_l/1e12;
            p90_l=p90_l/1e12;

            app.P90PJ.Value=p90_l;
            app.P50PJ.Value=p50_l;
            app.P10PJ.Value=p10_l;            
            
            %%%%%%%%%%%%%%% WAIT BAR FIGURE %%%%%%
            d.Value = .66; 
            d.Message = 'Ploting the results';
            pause(1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            
            %PLOT THE RESULTS IN PJ
            
            %HISTOGRAM
            histogram(app.UIAxes_two,HIT_U./1e12,xbins_U./1e12);
            xlabel(app.UIAxes_two,'HIP [PJ]');
            ylabel(app.UIAxes_two,'Frequency');
            name = 'HIP histogram of';
            extra = [name ' ' app.a];
            if app.Depthselection.Value == 1
            %app.Z_selec=num2str(app.Upperdepth.Value,'%4.2i');    
            extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
            end
            if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
            %app.Z_selec=num2str(app.Upperdepth.Value,'%4.2i');
            %app.Z_selec_b=num2str(app.Lowerdepth.Value,'%4.2i');
            extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
            end
            title (app.UIAxes_two,extra,"FontSize",14);
            
            %CUMULATIVE PROBABILITY CURVE
            
            plot(app.UIAxes_three,[p10_l p10_l],[0 100],'r','LineWidth',2);
            hold(app.UIAxes_three,'on');
            plot(app.UIAxes_three,[p50_l p50_l],[0 100],'Color',[0.0,0.0,1.0],'LineWidth',3);
            hold(app.UIAxes_three,'on');
            plot(app.UIAxes_three,[p90_l p90_l],[0 100],'Color',[0.47, 0.67, 0.19],'LineWidth',4);
            hold(app.UIAxes_three,'on');
    
            cdist_U_mod=1-cdist_U;
            plot(app.UIAxes_three,xbins_U/1e12,cdist_U_mod*100,'b','LineWidth',2);
            
    
            xlabel(app.UIAxes_three,'HIP [PJ]');
            app.UIAxes_three.YLim = [0 100];
            ylabel(app.UIAxes_three,'Cumulative Probability [%]');
            name = 'Cumulative Probability curve of';
            extra = [name ' ' app.a ' reserves'];
            if app.Depthselection.Value == 1
            extra = [name ' ' app.a ' resources from ' app.Z_selec ' m'];
            end
            if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
            extra = [name ' ' app.a ' resources between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
            end
            title (app.UIAxes_three,extra);
                     
            app.GeothermaltargetEditField.Value=app.lithologynameEditFiel.Value;
            app.NumberofsimulationsEditField.Value=app.SimulationNumber.Value;
            app.P90PJ.Value=p90_l;
            app.P50PJ.Value=p50_l;
            app.P10PJ.Value=p10_l;
            
            app.P90PJEditField.Value=p90_l;
            app.P50PJEditField.Value=p50_l;
            app.P10PJEditField.Value=p10_l;
            
            %% BUILDING THE FILES TO EXPORT AND PLOT%%   

            % HIT_LITO = X-Y-Z-MEAN HIT-STD HIT-TEMP- FOR EACH CELL

            %OBTAIN P_10 P_50 P_90 FOR EACH CELL OF THE MODEL

                p_10=zeros(app.A,1);
                p_50=zeros(app.A,1);
                p_90=zeros(app.A,1);
                
                p_10_Hrec=zeros(app.A,1);%MOD
                p_50_Hrec=zeros(app.A,1);%MOD
                p_90_Hrec=zeros(app.A,1);%MOD

                for i=1:app.A
    
                [min1,ind1]=min(abs(cdist(i,:)-0.9));
                [min2,ind2]=min(abs(cdist(i,:)-0.5));
                [min3,ind3]=min(abs(cdist(i,:)-0.1)); 
                
                [min1_Hrec,ind1_Hrec]=min(abs(cdist_Hrec(i,:)-0.9));%MOD
                [min2_Hrec,ind2_Hrec]=min(abs(cdist_Hrec(i,:)-0.5));%MOD
                [min3_Hrec,ind3_Hrec]=min(abs(cdist_Hrec(i,:)-0.1));%MOD
                
                % TO PETAJOULES
                p_10(i)=xbins(i,ind1)/1e12;
                p_50(i)=xbins(i,ind2)/1e12;
                p_90(i)=xbins(i,ind3)/1e12;
                
                % EN KILO WATTS    
                p_10_Hrec(i)=xbins_Hrec(i,ind1_Hrec);%MOD
                p_50_Hrec(i)=xbins_Hrec(i,ind2_Hrec);%MOD
                p_90_Hrec(i)=xbins_Hrec(i,ind3_Hrec);%MOD
    
                end

                %HIP
                HIT_A=HIT';
                Mean_HIT=mean(HIT_A);
                Std_HIT=std(HIT_A);
                Mean_HIT=Mean_HIT';
                Std_HIT=Std_HIT';
                %A PETAJOULES
                Mean_HIT=Mean_HIT./1e12;
                Std_HIT=Std_HIT./1e12;
                
                %Hrec 
                Hrec_A=Hrec';%MOD
                Mean_Hrec=mean(Hrec_A);%MOD
                Std_Hrec=std(Hrec_A);%MOD
                Mean_Hrec=Mean_Hrec';%MOD
                Std_Hrec=Std_Hrec';%MOD

                % X Y Z P_10 P_50 P_90 HIP_MEAN HIP_STD TEMP STD_TEMP LITO DEN
                if app.Poro_yes_no.Value == 0 && app.Den_yes_no.Value == 1 %A
                app.HIT_LITO=[app.dades_netes(:,app.col_X),app.dades_netes(:,app.col_Y),app.dades_netes(:,app.col_Z),p_10,p_50,p_90,Mean_HIT,Std_HIT,app.dades_netes(:,app.col_temp),app.dades_netes(:,app.col_std_temp),app.dades_netes(:,app.col_lito),app.dades_netes(:,app.col_den),p_10_Hrec,p_50_Hrec,p_90_Hrec,Mean_Hrec,Std_Hrec];
                end
                if app.Poro_yes_no.Value == 1 && app.Den_yes_no.Value == 1 %B
                app.HIT_LITO=[app.dades_netes(:,app.col_X),app.dades_netes(:,app.col_Y),app.dades_netes(:,app.col_Z),p_10,p_50,p_90,Mean_HIT,Std_HIT,app.dades_netes(:,app.col_temp),app.dades_netes(:,app.col_std_temp),app.dades_netes(:,app.col_lito),app.dades_netes(:,app.col_den),app.dades_netes(:,app.col_p),p_10_Hrec,p_50_Hrec,p_90_Hrec,Mean_Hrec,Std_Hrec];    
                end
                if app.Poro_yes_no.Value == 1 && app.Den_yes_no.Value == 0 %C
                app.HIT_LITO=[app.dades_netes(:,app.col_X),app.dades_netes(:,app.col_Y),app.dades_netes(:,app.col_Z),p_10,p_50,p_90,Mean_HIT,Std_HIT,app.dades_netes(:,app.col_temp),app.dades_netes(:,app.col_std_temp),app.dades_netes(:,app.col_lito),app.dades_netes(:,app.col_p),p_10_Hrec,p_50_Hrec,p_90_Hrec,Mean_Hrec,Std_Hrec];    
                end
                if app.Poro_yes_no.Value == 0 && app.Den_yes_no.Value == 0 %D
                app.HIT_LITO=[app.dades_netes(:,app.col_X),app.dades_netes(:,app.col_Y),app.dades_netes(:,app.col_Z),p_10,p_50,p_90,Mean_HIT,Std_HIT,app.dades_netes(:,app.col_temp),app.dades_netes(:,app.col_std_temp),app.dades_netes(:,app.col_lito),p_10_Hrec,p_50_Hrec,p_90_Hrec,Mean_Hrec,Std_Hrec];
                end
                app.HIT_LITO(app.HIT_LITO==0)=0;
                
%                 %% UNIT TEMPERATURE DISTRIBUTION
% 
%                 max_t=max(app.dades_netes(:,app.col_temp));
%                 min_t=min(app.dades_netes(:,app.col_temp));
%                 
%                 xbins_t=linspace(min_t,max_t,nbins);
%                 dist_t=hist(app.dades_netes(:,app.col_temp),xbins_t);
%                 
%                 %PLOT THE UNIT TEMPERATURE DISTRIBUTION
%                          
%                 histogram(app.UIAxes_ten_bis,app.dades_netes(:,app.col_temp),xbins_t);
%                 
%                 xlabel(app.UIAxes_ten_bis,'Temperature (ºC)');
%                 ylabel(app.UIAxes_ten_bis,'Counts');
%                 
%                 name = 'Temperature distribution of';
%                 extra = [name ' ' app.a];
%                 
%                 if app.Depthselection.Value == 1
%                 extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
%                 end
%                 if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
%                 extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
%                 end
%                 title (app.UIAxes_ten_bis,extra);
                
                
                %% Z SUM OF THE SELECTED CELLS TO 2D PLOTS
                
                HIT_SUM=[app.dades_netes(:,app.col_X), app.dades_netes(:,app.col_Y),p_10,p_50,p_90];
                Hrec_SUM=[app.dades_netes(:,app.col_X), app.dades_netes(:,app.col_Y),p_10_Hrec,p_50_Hrec,p_90_Hrec]; %MOD
                
                HIT_SUM_1=unique(HIT_SUM(:,1:2),'Rows');
                HIT_SUM_1=[HIT_SUM_1 zeros(size(HIT_SUM_1,1),1)];
                for k=1:size(HIT_SUM_1,1)
                HIT_SUM_1(k,3)=sum(HIT_SUM(and(HIT_SUM(:,1)==HIT_SUM_1(k,1),HIT_SUM(:,2)==HIT_SUM_1(k,2)),3));
                HIT_SUM_1(k,4)=sum(HIT_SUM(and(HIT_SUM(:,1)==HIT_SUM_1(k,1),HIT_SUM(:,2)==HIT_SUM_1(k,2)),4));
                HIT_SUM_1(k,5)=sum(HIT_SUM(and(HIT_SUM(:,1)==HIT_SUM_1(k,1),HIT_SUM(:,2)==HIT_SUM_1(k,2)),5));
                end
                
%                 max_x=max(HIT_SUM_1(:,1));
%                 min_x=min(HIT_SUM_1(:,1));
%                 max_y=max(HIT_SUM_1(:,2));
%                 min_y=min(HIT_SUM_1(:,2));


                Hrec_SUM_1=unique(Hrec_SUM(:,1:2),'Rows'); %MOD
                Hrec_SUM_1=[Hrec_SUM_1 zeros(size(Hrec_SUM_1,1),1)];%MOD
                for k=1:size(Hrec_SUM_1,1)%MOD
                Hrec_SUM_1(k,3)=sum(Hrec_SUM(and(Hrec_SUM(:,1)==Hrec_SUM_1(k,1),Hrec_SUM(:,2)==Hrec_SUM_1(k,2)),3));%MOD
                Hrec_SUM_1(k,4)=sum(Hrec_SUM(and(Hrec_SUM(:,1)==Hrec_SUM_1(k,1),Hrec_SUM(:,2)==Hrec_SUM_1(k,2)),4));%MOD
                Hrec_SUM_1(k,5)=sum(Hrec_SUM(and(Hrec_SUM(:,1)==Hrec_SUM_1(k,1),Hrec_SUM(:,2)==Hrec_SUM_1(k,2)),5));%MOD
                end
                
                                
                app.HIP_Hrec_2D=[HIT_SUM_1,Hrec_SUM_1(:,3),Hrec_SUM_1(:,4),Hrec_SUM_1(:,5)];%MOD
                app.HIP_Hrec_2D(app.HIP_Hrec_2D==0)=0;
                
                %%%%%%%%%%%%%%%%%%%%%%%%% WAIT BAR FIGURE %%%%%%%%%%
                % Finish calculations
                d.Value = 1;
                d.Message = 'Finishing';
                pause(1)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                             
                %SQUARE COORDINATES MATRIX 
                
                [x_plot,y_plot]=meshgrid(app.min_x:app.deltaX.Value:app.max_x, app.min_y:app.deltaY.Value:app.max_y);
                cell_surf=app.deltaX.Value*app.deltaY.Value*1e-6; %cell surface in Km2
                
                [GG,PP]=size(x_plot);
                
                %SQUARE HIP MATRIX

                [G ,P]=size(HIT_SUM_1);
                
                HIT_plot_10=zeros(GG,PP);
                HIT_plot_50=zeros(GG,PP);
                HIT_plot_90=zeros(GG,PP);
                
                Hrec_plot_10=zeros(GG,PP);%MOD
                Hrec_plot_50=zeros(GG,PP);%MOD
                Hrec_plot_90=zeros(GG,PP);%MOD
                
                for i = 1 : G
                    
                 [fil1,col1]=min(abs(x_plot(1,:)-HIT_SUM_1(i,1)));
                 [fil2,col2]=min(abs(y_plot(:,1)-HIT_SUM_1(i,2)));
                 
                 HIT_plot_10(col2,col1)=HIT_SUM_1(i,3);
                 HIT_plot_50(col2,col1)=HIT_SUM_1(i,4);
                 HIT_plot_90(col2,col1)=HIT_SUM_1(i,5);
                 
                 
                 [fil1_Hrec,col1_Hrec]=min(abs(x_plot(1,:)-Hrec_SUM_1(i,1)));%MOD
                 [fil2_Hrec,col2_Hrec]=min(abs(y_plot(:,1)-Hrec_SUM_1(i,2)));%MOD
                 
                 Hrec_plot_10(col2_Hrec,col1_Hrec)=Hrec_SUM_1(i,3);%MOD
                 Hrec_plot_50(col2_Hrec,col1_Hrec)=Hrec_SUM_1(i,4);%MOD
                 Hrec_plot_90(col2_Hrec,col1_Hrec)=Hrec_SUM_1(i,5);%MOD
                    
                end
                
                %PLOT THE HIP P10 DISTRIBUTION IN 2D
                HIT_plot_10(HIT_plot_10==0)=NaN;
                s=pcolor(app.UIAxes_four,x_plot,y_plot,HIT_plot_10/cell_surf);
                s.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_four);
                %caxis([0 14]);
                xlabel(app.UIAxes_four,'X [m]');
                ylabel(app.UIAxes_four,'Y [m]');
                c.Label.String = 'HIP [PJ/km2]';
                c.AxisLocation ='in';
                %We select the color bar limits as function of third column from the
                %HIT_SUM_1 matrix. This column is the p_10 summatory in Z
                %for each X Y pair. As p_10 means "10% of probability" the
                %values are higher than p_50 and p_90 so is usefull to
                %define the colorbar limits.
                min_val=min(HIT_SUM_1(:,3))/cell_surf;
                max_val=max(HIT_SUM_1(:,3))/cell_surf;
                c.Limits =[min_val max_val];
                
                %app.max_val_m.Value=max_val; %mod_prova
                %app.min_val_m.Value=min_val; %mod_prova
                
                name = 'HIP P10 of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_four,extra);
                
                
                %PLOT THE HIP P50 DISTRIBUTION IN 2D
                HIT_plot_50(HIT_plot_50==0)=NaN;
                q=pcolor(app.UIAxes_six,x_plot,y_plot,HIT_plot_50/cell_surf);
                q.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_six);
                %caxis([0 14]);
                xlabel(app.UIAxes_six,'X [m]');
                ylabel(app.UIAxes_six,'Y [m]');
                c.Label.String = 'HIP [PJ/km2]';
                c.AxisLocation ='in';
                c.Limits =[min_val max_val];
                
                name = 'HIP P50 of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_six,extra);
                
                %PLOT THE HIP P90 DISTRIBUTION IN 2D
                HIT_plot_90(HIT_plot_90==0)=NaN;
                q=pcolor(app.UIAxes_five,x_plot,y_plot,HIT_plot_90/cell_surf);
                q.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_five);
                %caxis([0 14]);
                xlabel(app.UIAxes_five,'X [m]');
                ylabel(app.UIAxes_five,'Y [m]');
                c.Label.String = 'HIP [PJ/km2]';
                c.AxisLocation ='in';
                c.Limits =[min_val max_val];
                
                
                name = 'HIP P90 of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_five,extra);


            %TOP TEMPERATURE 
            
           TEMP=[app.dades_netes(:,app.col_X), app.dades_netes(:,app.col_Y),app.dades_netes(:,app.col_Z) app.dades_netes(:,app.col_temp)];
           
           [AT,BT]=size(TEMP);

           [BOUND_X_Y,nn,uu]=unique(TEMP(:,1:2),'Rows');
           
           A_D=[];

            for k=1:max(uu)
                rr=TEMP(uu==k,:);
                [fi,co]=max(rr(:,3));
                tt=rr(co,:);
                A_D=[A_D;tt];
            end
            
            A_D(:,3)=[];
            TEMP=A_D;
            [AT,BT]=size(TEMP);
                        
            TEMP_M=zeros(GG,PP);
            
            for i = 1 : AT
                    
                 [fil1,col1]=min(abs(x_plot(1,:)-TEMP(i,1)));
                 [fil2,col2]=min(abs(y_plot(:,1)-TEMP(i,2)));
                 
                 TEMP_M(col2,col1)=TEMP(i,3);
                  
            end
            
            %PLOT THE TOP TEMPERATURE IN 2D
                TEMP_M(TEMP_M==0)=NaN;
                q=pcolor(app.UIAxes_seven,x_plot,y_plot,TEMP_M);
                q.EdgeColor = 'none';
                colormap(app.UIAxes_seven,'jet');
                %s.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_seven);
                xlabel(app.UIAxes_seven,'X [m]');
                ylabel(app.UIAxes_seven,'Y [m]');
                c.Label.String = 'Temperature [ºC]';
                c.AxisLocation ='in';
                                
                name = 'Temperature at the top of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_seven,extra);


           %PLOT THE Hrec IN KW/KM2
           
                %PLOT THE Hrec P10 DISTRIBUTION IN 2D
                Hrec_plot_10(Hrec_plot_10==0)=NaN;
                s=pcolor(app.UIAxes_eleven,x_plot,y_plot,Hrec_plot_10/cell_surf);
                s.EdgeColor = 'none';
                %s.LineWidth =0.0001;
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_eleven);
                %caxis([0 14]);
                xlabel(app.UIAxes_eleven,'X [m]');
                ylabel(app.UIAxes_eleven,'Y [m]');
                c.Label.String = 'Hrec [kW/km2]';
                c.AxisLocation ='in';
                %We select the color bar limits as function of third column from the
                %HIT_SUM_1 matrix. This column is the p_10 summatory in Z
                %for each X Y pair. As p_10 means "10% of probability" the
                %values are higher than p_50 and p_90 so is usefull to
                %define the colorbar limits.
                min_val_Hrec=min(Hrec_SUM_1(:,3))/cell_surf;
                max_val_Hrec=max(Hrec_SUM_1(:,3))/cell_surf;
                c.Limits =[min_val_Hrec max_val_Hrec];
                
                
                name = 'Hrec P10 of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_eleven,extra);
                
                
                %PLOT THE Hrec P50 DISTRIBUTION IN 2D
                Hrec_plot_50(Hrec_plot_50==0)=NaN;
                q=pcolor(app.UIAxes_twelve,x_plot,y_plot,Hrec_plot_50/cell_surf);
                q.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_twelve);
                %caxis([0 14]);
                xlabel(app.UIAxes_twelve,'X [m]');
                ylabel(app.UIAxes_twelve,'Y [m]');
                c.Label.String = 'Hrec [kW/km2]';
                c.AxisLocation ='in';
                c.Limits =[min_val_Hrec max_val_Hrec];
                
                name = 'Hrec P50 of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_twelve,extra);
                
                %PLOT THE Hrec P90 DISTRIBUTION IN 2D
                Hrec_plot_90(Hrec_plot_90==0)=NaN;
                q=pcolor(app.UIAxes_thirteen,x_plot,y_plot,Hrec_plot_90/cell_surf);
                q.EdgeColor = 'none';
                %contour(x_plot,y_plot,vq,'ShowText','off','Fill','on');
                %pcolor(HIT_SUM_1);
                c=colorbar(app.UIAxes_thirteen);
                %caxis([0 14]);
                xlabel(app.UIAxes_thirteen,'X [m]');
                ylabel(app.UIAxes_thirteen,'Y [m]');
                c.Label.String = 'Hrec [kW/km2]';
                c.AxisLocation ='in';
                c.Limits =[min_val_Hrec max_val_Hrec];
                
                
                name = 'Hrec P90 of ';
                extra = [name ' ' app.a];
                
                if app.Depthselection.Value == 1
                extra = [name ' ' app.a ' from ' app.Z_selec ' m'];
                end
                if app.Depthselection.Value == 1 && app.Lowerdepth.Value ~= 0
                extra = [name ' ' app.a ' between ' app.Z_selec ' and ' app.Z_selec_b ' m'];    
                end
                title (app.UIAxes_thirteen,extra);

                %%%%%%%%%%%%%%%% WAIT BAR FIGURE %%%%%%%%%%%
                % Close dialog box
                close(d);
                close(f);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Button pushed function: HIPandTmapsButton
        function P_B_RESULTS_VIE(app, event)
            app.TabGroup.SelectedTab = app.HIPandTmapsTab;
        end

        % Button pushed function: 
        % GeothermalreservoirmapsamdThistogramButton
        function P_B_GEOMETRY(app, event)
            app.TabGroup.SelectedTab = app.GeothermalreservoirmapsandThistogramTab;
        end

        % Button pushed function: HRECButton
        function P_B_Hrec(app, event)
            app.TabGroup.SelectedTab = app.HrecmapsTab;
        end

        % Button pushed function: ExportresultsButton
        function P_B_EXPORT_DATA(app, event)
            app.TabGroup.SelectedTab = app.ExportresultsTab;
        end

        % Button pushed function: BROWSEFile1
        function ExpFile1(app, event)
            [BFileName, BFilePath] = uiputfile('*.txt');
            BePath = fullfile(BFilePath,BFileName);
            app.DirectoryFile1.Value = BePath;
            app.NAMEFile1.Value=BFileName;
            figure(app.HIP3D_Calculator);
        end

        % Button pushed function: BROWSEFile2
        function ExpFile2(app, event)
            [CFileName, CFilePath] = uiputfile('*.txt');
            CePath = fullfile(CFilePath,CFileName);
            app.DirectoryFile2.Value = CePath;
            app.NAMEFile2.Value=CFileName;
            figure(app.HIP3D_Calculator);
        end

        % Button pushed function: BROWSEReport
        function ExpReport(app, event)
            [EFileName, EFilePath] = uiputfile('*.txt');
            EePath = fullfile(EFilePath,EFileName);
            app.DirectoryReport.Value = EePath;
            app.NAMEReport.Value=EFileName;
            figure(app.HIP3D_Calculator);
        end

        % Button pushed function: RUN5Button
        function RUN5ExpDat(app, event)
            
            %File 1 to export
            
            if app.Poro_yes_no.Value==0 && app.Den_yes_no.Value==1 %A
            ide_col = {'X','Y','Z','p_10_HIP','p_50_HIP','p_90_HIP','MEAN_HIP','STD_HIP','T','STD_T','GEO','DENSITY','p_10_Hrec','p_50_Hrec','p_90_Hrec','Mean_Hrec','Std_Hrec'};
            end
            if app.Poro_yes_no.Value ==1 && app.Den_yes_no.Value == 1 %B
            ide_col = {'X','Y','Z','p_10_HIP','p_50_HIP','p_90_HIP','MEAN_HIP','STD_HIP','T','STD_T','GEO','DENSITY','POROSITY','p_10_Hrec','p_50_Hrec','p_90_Hrec','Mean_Hrec','Std_Hrec'};    
            end
            if app.Poro_yes_no.Value ==1 && app.Den_yes_no.Value == 0 %C
            ide_col = {'X','Y','Z','p_10_HIP','p_50_HIP','p_90_HIP','MEAN_HIP','STD_HIP','T','STD_T','GEO','POROSITY','p_10_Hrec','p_50_Hrec','p_90_Hrec','Mean_Hrec','Std_Hrec'};    
            end
            if app.Poro_yes_no.Value==0 && app.Den_yes_no.Value==0 %D
            ide_col = {'X','Y','Z','p_10_HIP','p_50_HIP','p_90_HIP','MEAN_HIP','STD_HIP','T','STD_T','GEO','p_10_Hrec','p_50_Hrec','p_90_Hrec','Mean_Hrec','Std_Hrec'};
            end
            
            dir1=app.DirectoryFile1.Value;
                        
            fmt=repmat(',%.12g',1,size(app.HIT_LITO,2));
            fmt=[fmt(2:end),'\r\n'];
            %[fid,msg]=fopen(fullfile(dir1,NameF1),'w');
            [fid,msg]=fopen(dir1,'w');
            assert(fid>=3,msg);
            fprintf(fid,'%s',ide_col{1});
            fprintf(fid,',%s',ide_col{2:end});
            fprintf(fid,'\r\n');
            fprintf(fid,fmt,app.HIT_LITO.');
            fclose(fid);
            
            %File 2 to export
                        
            ide_col_2 = {'X','Y','P10_HIP','P50_HIP','P90_HIP','P10_Hrec','P50_Hrec','P90_Hrec'};
            dir2=app.DirectoryFile2.Value;
                                 
            fmt=repmat(',%.12g',1,size(app.HIP_Hrec_2D,2));
            fmt=[fmt(2:end),'\r\n'];
            [fid,msg]=fopen(dir2,'w');
            assert(fid>=3,msg);
            fprintf(fid,'%s',ide_col_2{1});
            fprintf(fid,',%s',ide_col_2{2:end});
            fprintf(fid,'\r\n');
            fprintf(fid,fmt,app.HIP_Hrec_2D.');
            fclose(fid);
            
            %Create the report
            
            dir3=app.DirectoryReport.Value;
            fid=fopen(dir3,'w');
            fprintf (fid,'------------------ \r\n');
            fprintf (fid,'SIMULATION REPORT \r\n');
            fprintf (fid,'------------------ \r\n');
            fprintf (fid,'\r\n');
            fprintf (fid,'3DHIP-CALCULATOR Version 1.1. \r\n');
%             fprintf (fid,'Contributors: \r\n');
%             fprintf (fid,'Guillem Piris \r\n');
%             fprintf (fid,'guillem.piris@uab.cat \r\n');
%             fprintf (fid,'guillem.piris@gmail.com \r\n');
%             fprintf (fid,'Albert Griera (Universitat Autònoma de Barcelona) \r\n');
%             fprintf (fid,'Ignasi Herms (Institut Cartogràfic i Geològic de Catalunya) \r\n');
%             fprintf (fid,'Enrique Gomez-Rivas (Universitat de Barcelona) \r\n');
%             fprintf (fid,'This code is free and is able to you to make it better, but do no forget us :) \r\n');
            fprintf (fid,'December 2020 \r\n');
            fprintf (fid,'\r\n');
            fprintf (fid,'\r\n');
            fprintf (fid,'1. LOAD DATA (GEOLOGICAL AND THERMAL MODELS) \r\n');
            fprintf (fid,'Input Geological Model: %s \r\n',app.GeologicalModelField.Value);
            if app.Thermalgradient.Value ==0
            fprintf (fid,'Input Thermal Model: %s \r\n',app.ThermalModelField.Value);   
            else
            fprintf (fid,'Input Thermal Gradient [ºC/km]: %6.2f \r\n',app.Thermalgradient.Value);
            fprintf (fid,'Input Surface Temperature [ºC] %6.2f \r\n',app.SurfaceTemperature.Value);
            end
            fprintf (fid,'\r\n');
            fprintf (fid,'2.HEAT-IN-PLACE SIMULATION PARAMETERS \r\n');
            fprintf (fid,'Heat-In-Place simulation number: %1i \r\n',app.SimulationNumber.Value);
            fprintf (fid,'Target numerical code: %1i \r\n',app.SelectLithlogyCodeEditField.Value);
            fprintf (fid,'Target name: %s \r\n',app.lithologynameEditFiel.Value);
            fprintf (fid,'CELL VOLUME [m3] \r\n');
            fprintf (fid,'Delta X [m]: %6.2f \r\n',app.deltaX.Value);
            fprintf (fid,'Delta Y [m]: %6.2f \r\n',app.deltaY.Value);
            fprintf (fid,'Delta Z [m]: %6.2f \r\n',app.deltaZ.Value);
            fprintf (fid,'\r\n');
            fprintf (fid,'3. DEPTH RANGE SELECTION \r\n');
            if app.Depthselection.Value == 0
            fprintf (fid,'No depth range selections were applied \r\n');
            else
            if app.Lowerdepth.Value == 0
            fprintf (fid,'Depth range from %6.2f m to %6.2f m \r\n',app.Upperdepth.Value,app.min_Z);    
            else
            fprintf (fid,'Depth range between %6.2f m and %6.2f m \r\n',app.Upperdepth.Value,app.Lowerdepth.Value);
            end
            end
            fprintf (fid,'\r\n');
            fprintf (fid,'4. PETROPHYSICAL PROPERTIES AND OTHER PARAMETERS \r\n');
            fprintf (fid,'HEAT-IN-PLACE VALUES \r\n');
            if app.Poro_yes_no.Value == 1
            fprintf (fid,'The Porosity is already in the geological model \r\n');
            else
            if app.Check_N_Poro.Value ==1
            fprintf (fid,'The Porosity follows a Normal Ditribution \r\n');
            fprintf (fid,'Mean: %6.2f \r\n',app.Mean_Porosity.Value);
            fprintf (fid,'Standard deviation: %6.2f \r\n',app.Std_Porosity.Value);
            end
            if app.Check_T_Poro.Value ==1
            fprintf (fid,'The Porosity follows a Triangular Ditribution \r\n');
            fprintf (fid,'Lower value: %6.2f \r\n',app.Low_Porosity.Value);
            fprintf (fid,'Maximum value: %6.2f \r\n',app.Max_Porosity.Value);
            fprintf (fid,'Upper value: %6.2f \r\n',app.Upp_Porosity.Value);
            end
            end
            if app.Den_yes_no.Value == 1
            fprintf (fid,'The Rock Density [g/cm3] is already in the geological model \r\n');
            else
            if app.Check_N_LDen.Value ==1
            fprintf (fid,'The Rock Density [kg/m3] follows a Normal Ditribution \r\n');
            fprintf (fid,'Mean: %6.2f \r\n',app.Mean_Lito_Density.Value);
            fprintf (fid,'Standard deviation: %6.2f \r\n',app.Std_Lito_Density.Value);
            end
            if app.Check_T_LDen.Value ==1
            fprintf (fid,'The Rock Density [kg/m3] follows a Triangular Ditribution \r\n');
            fprintf (fid,'Lower value: %6.2f \r\n',app.Low_Lito_Density.Value);
            fprintf (fid,'Maximum value: %6.2f \r\n',app.Max_Lito_Density.Value);
            fprintf (fid,'Upper value: %6.2f \r\n',app.Upp_Lito_Density.Value);
            end
            end
            if app.Check_N_FDen.Value ==1
            fprintf (fid,'The Fluid Density [kg/m3] follows a Normal Ditribution \r\n');
            fprintf (fid,'Mean: %6.2f \r\n',app.Mean_Fluid_Density.Value);
            fprintf (fid,'Standard deviation: %6.2f \r\n',app.Std_Fluid_Density.Value);
            end
            if app.Check_T_FDen.Value ==1
            fprintf (fid,'The Fluid Density [kg/m3] follows a Triangular Ditribution \r\n');
            fprintf (fid,'Lower value: %6.2f \r\n',app.Low_Fluid_Density.Value);
            fprintf (fid,'Maximum value: %6.2f \r\n',app.Max_Fluid_Density.Value);
            fprintf (fid,'Upper value: %6.2f \r\n',app.Upp_Fluid_Density.Value);
            end
            if app.Check_N_FVol.Value ==1
            fprintf (fid,'The Fluid Specific Heat Capacity [kJ/kg·ºC] follows a Normal Ditribution \r\n');
            fprintf (fid,'Mean: %6.2f \r\n',app.Mean_Fluidvolumetricheatcapacity.Value);
            fprintf (fid,'Standard deviation: %6.2f \r\n',app.Std_Fluidvolumetricheatcapacity.Value);
            end
            if app.Check_T_FVol.Value ==1
            fprintf (fid,'The Fluid Specific Heat Capacity [kJ/kg·ºC] follows a Triangular Ditribution \r\n');
            fprintf (fid,'Lower value: %6.2f \r\n',app.Low_Fluid_Volumetric.Value);
            fprintf (fid,'Maximum value: %6.2f \r\n',app.Max_Fluid_Volumetric.Value);
            fprintf (fid,'Upper value: %6.2f \r\n',app.Upp_Fluid_Volumetric.Value);
            end
            if app.Check_N_LVol.Value ==1
            fprintf (fid,'The Rock Specific Heat Capacity [kJ/kg·ºC] follows a Normal Ditribution \r\n');
            fprintf (fid,'Mean: %6.2f \r\n',app.Mean_Litovolumetricheatcapacity.Value);
            fprintf (fid,'Standard deviation: %6.2f \r\n',app.Std_Litovolumetricheatcapacity.Value);
            end
            if app.Check_T_LVol.Value ==1
            fprintf (fid,'The Rock Specific Heat Capacity [kJ/kg·ºC] follows a Triangular Ditribution \r\n');
            fprintf (fid,'Lower value: %6.2f \r\n',app.Low_Lito_Volumetric.Value);
            fprintf (fid,'Maximum value: %6.2f \r\n',app.Max_Lito_Volumetric.Value);
            fprintf (fid,'Upper value: %6.2f \r\n',app.Upp_Lito_Volumetric.Value);
            end
            fprintf (fid,'Reinjection Temperature [ºC]: %6.2f \r\n',app.ReinjectionTemp.Value);
            fprintf (fid,'RECOVERABLE HEAT \r\n');
            if app.Check_N_RF.Value ==1
            fprintf (fid,'The Recovery Factor follows a Normal Ditribution \r\n');
            fprintf (fid,'Mean: %6.2f \r\n',app.Mean_RF.Value);
            fprintf (fid,'Standard deviation: %6.2f \r\n',app.Std_RF.Value);
            end
            if app.Check_T_RF.Value ==1
            fprintf (fid,'The Recovery Factor follows a Triangular Ditribution \r\n');
            fprintf (fid,'Lower value: %6.2f \r\n',app.Low_RF.Value);
            fprintf (fid,'Maximum value: %6.2f \r\n',app.Max_RF.Value);
            fprintf (fid,'Upper value: %6.2f \r\n',app.Upp_RF.Value);
            end
            fprintf (fid,'Plant Factor: %6.2f \r\n',app.PlantFactor.Value);
            fprintf (fid,'The Mean Plant Lifetime [Years]: %6.2f \r\n',app.Live_Time.Value);
            fprintf (fid,'Conversion Efficiency: %6.2f \r\n',app.ConversionEfficiency.Value);
            fprintf (fid,'\r\n');
            fprintf (fid,'HEAT-IN-PLACE RESULTS \r\n');
            if app.Depthselection.Value == 0
            fprintf (fid,'The Heat-In-Place results of %s are: \r\n',app.a);
            else
            if app.Lowerdepth.Value ==0
            fprintf (fid,'The Heat-In-Place results of %s between %6.2f m and %6.2f m are: \r\n',app.a,app.Upperdepth.Value,app.min_Z);    
            else
            fprintf (fid,'The Heat-In-Place results of %s between %6.2f m and %6.2f m are: \r\n',app.a,app.Upperdepth.Value,app.Lowerdepth.Value);
            end
            end
            fprintf (fid,'P90 [PJ]: %6.2f \r\n',app.P90PJ.Value);
            fprintf (fid,'P50 [PJ]: %6.2f \r\n',app.P50PJ.Value);
            fprintf (fid,'P10 [PJ]: %6.2f \r\n',app.P10PJ.Value);
            fprintf (fid,'\r\n');
            fprintf (fid,'5. EXPORT FILES \r\n');
            fprintf (fid,'Output File 1:');
            fprintf (fid,' %s \r\n',app.DirectoryFile1.Value);
            fprintf (fid,'Output File 2:');
            fprintf (fid,' %s \r\n',app.DirectoryFile2.Value);
            fprintf (fid,'Report File:');
            fprintf (fid,' %s \r\n',app.DirectoryReport.Value);
            fclose(fid);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HIP3D_Calculator = uifigure('Visible', 'off');
            app.HIP3D_Calculator.Name = '3DHIP-Calculator';
            app.TabGroup = uitabgroup(app.HIP3D_Calculator);
            app.AppTab = uitab(app.TabGroup);
            app.AppTab.Title = 'App';
            app.Logo_UAB = uiimage(app.AppTab);
            app.Logo_UAB.ImageSource = 'logoUAB.svg';
            app.Image = uiimage(app.AppTab);
            app.Image.ImageSource = 'Logo_3DHIP.png';
            app.Panel_8 = uipanel(app.AppTab);
            app.THISAPPLICATIONLabel = uilabel(app.Panel_8);
            app.THISAPPLICATIONLabel.Text = {'3DHIP-Calculator allows to perform stochastic estimations of  the Heat-In-Place and the Recoverable '; 'Heat  energy  from 3D Geological  and Thermal  voxel models. The  input values  are  introduced  as'; 'Probability   Distributions   Functions  (PDF’s).  The  output  data  can  be  used   to  create  maps  of'; 'probability of deep geothermal resources using GIS desktop software.'; ''};
            app.THISAPPLICATIONLabel_4 = uilabel(app.Panel_8);
            app.THISAPPLICATIONLabel_4.Text = {'Guillem Piris (Universitat Autònoma de Barcelona, UAB) '; 'Ignasi Herms (Institut Cartogràfic i Geològic de Catalunya, ICGC)'; 'Albert Griera (Universitat Autònoma de Barcelona, UAB)'; 'Enrique Gomez-Rivas (Universitat de Barcelona, UB)'; 'Montse Colomer (Institut Cartogràfic i Geològic de Catalunya, ICGC)'; ''};
            app.THISAPPLICATIONLabel_5 = uilabel(app.Panel_8);
            app.THISAPPLICATIONLabel_5.Text = {'The code has been programmed in MATLAB (v. 2019b)'; ''; ''};
            app.StartButton = uibutton(app.Panel_8, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_START, true);
            app.StartButton.Text = 'Start';
            app.WorkflowButton = uibutton(app.Panel_8, 'push');
            app.WorkflowButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_INTRODUCTION, true);
            app.WorkflowButton.Text = 'Workflow';
            app.Version12August2022Label = uilabel(app.Panel_8);
            app.Version12August2022Label.Text = 'Version 1.2 (August 2022)';
            app.THISAPPLICATIONLabel_6 = uilabel(app.Panel_8);
            app.THISAPPLICATIONLabel_6.Text = 'Authors';
            app.THISAPPLICATIONLabel_7 = uilabel(app.Panel_8);
            app.THISAPPLICATIONLabel_7.Text = 'Overview';
            app.EmailcontactLabel = uilabel(app.Panel_8);
            app.EmailcontactLabel.Text = 'E-mail contact:';
            app.geotermiaicgccatLabel = uilabel(app.Panel_8);
            app.geotermiaicgccatLabel.Text = 'geotermia@icgc.cat';
            app.Logo_ICGC = uiimage(app.AppTab);
            app.Logo_ICGC.ImageSource = 'logoICGC.svg';
            app.THISAPPISDEVELOPEDUNDERLabel = uilabel(app.AppTab);
            app.THISAPPISDEVELOPEDUNDERLabel.Text = {'The  development  of  3DHIP-Calculator  was supported  by an  Industrial'; 'Doctorate  gran  funded  by  the AGAUR programme  2016-DI-031 and  by'; 'the  Institut   Cartogràfic  i  Geològic  de   Catalunya  (ICGC)  through  the '; 'project  GeoEnergy  CP-I  (2014-2017), CP-II (2018)  and CP-III (2019-2022).'; ''};
            app.Image2 = uiimage(app.AppTab);
            app.Image2.ImageSource = 'Well_image.jpg';
            app.DrillingsiteBlancMesnilGeothermaldistrictheatingLabel = uilabel(app.AppTab);
            app.DrillingsiteBlancMesnilGeothermaldistrictheatingLabel.Text = {'Drilling site: Blanc-Mesnil Geothermal district'; 'heating, Paris basin (photo: ICGC Jan. 2016)'};
            app.Image7 = uiimage(app.AppTab);
            app.Image7.ImageSource = 'licence.png';
            app.CCBY40Label = uilabel(app.AppTab);
            app.CCBY40Label.Text = 'CC BY 4.0';
            app.WorkflowTab = uitab(app.TabGroup);
            app.WorkflowTab.Title = 'Workflow';
            app.Image11 = uiimage(app.WorkflowTab);
            app.Image11.ImageSource = 'workflow_diagram1_2.png';
            app.Image3_6 = uiimage(app.WorkflowTab);
            app.Image3_6.ImageSource = 'Mini-Logo_3DHIP.png';
            app.Softwareusermanualv12inPDFButton = uibutton(app.WorkflowTab, 'push');
            app.Softwareusermanualv12inPDFButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_MANUAL, true);
            app.Softwareusermanualv12inPDFButton.Text = 'Software user manual v1.2 (in PDF)';
            app.GeologicalandthermalmodelsTab = uitab(app.TabGroup);
            app.GeologicalandthermalmodelsTab.Title = 'Geological and thermal models';
            app.UIAxes_one = uiaxes(app.GeologicalandthermalmodelsTab);
            title(app.UIAxes_one, 'Distribution of Temperature at Depth')
            xlabel(app.UIAxes_one, 'Temperature [ºC]')
            ylabel(app.UIAxes_one, 'Altitude [m a.s.l.]')
            app.Load_data = uipanel(app.GeologicalandthermalmodelsTab);
            app.Load_data.Title = '1. Load data (Geological and Thermal models)';
            app.GeologicalModelPanel = uipanel(app.Load_data);
            app.GeologicalModelPanel.Title = 'Geological Model';
            app.GeologicalModelEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.GeologicalModelEditFieldLabel.Text = 'Geological Model';
            app.GeologicalModelField = uieditfield(app.GeologicalModelPanel, 'text');
            app.BrowseButton = uibutton(app.GeologicalModelPanel, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @browse_geo, true);
            app.BrowseButton.Text = 'Browse';
            app.XEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GeologicalModelPanel, 'text');
            app.XEditField.Value = 'X';
            app.YEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GeologicalModelPanel, 'text');
            app.YEditField.Value = 'Y';
            app.ZEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GeologicalModelPanel, 'text');
            app.ZEditField.Value = 'Z';
            app.GeologyEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.GeologyEditFieldLabel.Text = 'Geology';
            app.GeologyEditField = uieditfield(app.GeologicalModelPanel, 'text');
            app.GeologyEditField.Value = 'Lithology';
            app.Den_yes_no = uicheckbox(app.GeologicalModelPanel);
            app.Den_yes_no.Text = {'Select if your geological'; 'model has density values'};
            app.DensityEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.GeologicalModelPanel, 'text');
            app.DensityEditField.Value = 'Density';
            app.Poro_yes_no = uicheckbox(app.GeologicalModelPanel);
            app.Poro_yes_no.Text = {'Select if your geological'; 'model has porosity values'};
            app.PorosityEditFieldLabel = uilabel(app.GeologicalModelPanel);
            app.PorosityEditFieldLabel.Text = 'Porosity';
            app.PorosityEditField = uieditfield(app.GeologicalModelPanel, 'text');
            app.PorosityEditField.Value = 'Porosity';
            app.ThermalModelPanel = uipanel(app.Load_data);
            app.ThermalModelPanel.Title = 'Thermal Model';
            app.ThermalModelEditFieldLabel = uilabel(app.ThermalModelPanel);
            app.ThermalModelEditFieldLabel.Text = 'Thermal Model';
            app.ThermalModelField = uieditfield(app.ThermalModelPanel, 'text');
            app.BrowseButton_2 = uibutton(app.ThermalModelPanel, 'state');
            app.BrowseButton_2.ValueChangedFcn = createCallbackFcn(app, @browse_ther, true);
            app.BrowseButton_2.Text = 'Browse';
            app.TemperatureTEditFieldLabel = uilabel(app.ThermalModelPanel);
            app.TemperatureTEditFieldLabel.Text = 'Temperature (T) ';
            app.TemperatureTEditField = uieditfield(app.ThermalModelPanel, 'text');
            app.TemperatureTEditField.Value = 'Mean_Temperature';
            app.Label = uilabel(app.ThermalModelPanel);
            app.Label.Text = {'If Thermal gradient is equal to 0 the user must to introduce a'; 'thermal model. If not, the code will use the thermal gradient. '};
            app.Std_TemperaureEditFieldLabel = uilabel(app.ThermalModelPanel);
            app.Std_TemperaureEditFieldLabel.Text = 'Std_Temperaure';
            app.Std_TemperaureEditField = uieditfield(app.ThermalModelPanel, 'text');
            app.Std_TemperaureEditField.Value = 'Stddev_Temperature';
            app.ThermalgradientCkmLabel = uilabel(app.ThermalModelPanel);
            app.ThermalgradientCkmLabel.Text = 'Thermal gradient [ºC/km]';
            app.Thermalgradient = uieditfield(app.ThermalModelPanel, 'numeric');
            app.SurfaceTemperatureCLabel = uilabel(app.ThermalModelPanel);
            app.SurfaceTemperatureCLabel.Text = 'Surface Temperature [ºC]';
            app.SurfaceTemperature = uieditfield(app.ThermalModelPanel, 'numeric');
            app.HITDATA = uipanel(app.GeologicalandthermalmodelsTab);
            app.HITDATA.Title = '2.Heat-In-Place simulation parameters';
            app.NumberofsimulationsLabel = uilabel(app.HITDATA);
            app.NumberofsimulationsLabel.Text = 'Number of simulations';
            app.SimulationNumber = uieditfield(app.HITDATA, 'numeric');
            app.SimulationNumber.Value = 1000;
            app.SelecttargetnumericalIDLabel = uilabel(app.HITDATA);
            app.SelecttargetnumericalIDLabel.Text = 'Select target (numerical ID)';
            app.SelectLithlogyCodeEditField = uieditfield(app.HITDATA, 'numeric');
            app.SelectLithlogyCodeEditField.Value = 4;
            app.IndicatethetargetnameLabel = uilabel(app.HITDATA);
            app.IndicatethetargetnameLabel.Text = 'Indicate the target name';
            app.lithologynameEditFiel = uieditfield(app.HITDATA, 'text');
            app.lithologynameEditFiel.Value = 'CRETACEOUS';
            app.Panel = uipanel(app.HITDATA);
            app.CellVolumem3Label = uilabel(app.Panel);
            app.CellVolumem3Label.Text = 'Cell Volume (m3)';
            app.XmEditFieldLabel = uilabel(app.Panel);
            app.XmEditFieldLabel.Text = 'ΔX (m)';
            app.deltaX = uieditfield(app.Panel, 'numeric');
            app.deltaX.ValueDisplayFormat = '%.3f';
            app.deltaX.Value = 693.825;
            app.YmEditFieldLabel = uilabel(app.Panel);
            app.YmEditFieldLabel.Text = 'ΔY (m)';
            app.deltaY = uieditfield(app.Panel, 'numeric');
            app.deltaY.ValueDisplayFormat = '%.3f';
            app.deltaY.Value = 572.95;
            app.ZmEditFieldLabel = uilabel(app.Panel);
            app.ZmEditFieldLabel.Text = 'ΔZ (m)';
            app.deltaZ = uieditfield(app.Panel, 'numeric');
            app.deltaZ.ValueDisplayFormat = '%.3f';
            app.deltaZ.Value = 64.285;
            app.Indicatetheaircodeinyourmodelifitexistsifnotleave99Label = uilabel(app.HITDATA);
            app.Indicatetheaircodeinyourmodelifitexistsifnotleave99Label.Text = {'Indicate the ''air'' code in your model'; '(if it exists, if not leave -99)'};
            app.aircode = uieditfield(app.HITDATA, 'numeric');
            app.aircode.Value = -99;
            app.RUN_1_2 = uibutton(app.GeologicalandthermalmodelsTab, 'state');
            app.RUN_1_2.ValueChangedFcn = createCallbackFcn(app, @RUN12Button, true);
            app.RUN_1_2.Text = {'RUN'; '(1. & 2.)'};
            app.DepthrangeselectionPanel = uipanel(app.GeologicalandthermalmodelsTab);
            app.DepthrangeselectionPanel.Title = '3. Depth range selection';
            app.Depthselection1Yes0NoLabel = uilabel(app.DepthrangeselectionPanel);
            app.Depthselection1Yes0NoLabel.Text = 'Depth selection (1 "Yes"; 0 "No")';
            app.Depthselection = uieditfield(app.DepthrangeselectionPanel, 'numeric');
            app.Depthselection.Value = 1;
            app.IfYesfillthefieldsbelowinmetersPanel = uipanel(app.DepthrangeselectionPanel);
            app.IfYesfillthefieldsbelowinmetersPanel.Title = 'If "Yes" fill the fields below in meters';
            app.UpperdepthmaslLabel = uilabel(app.IfYesfillthefieldsbelowinmetersPanel);
            app.UpperdepthmaslLabel.Text = 'Upper depth [m a.s.l.]';
            app.Upperdepth = uieditfield(app.IfYesfillthefieldsbelowinmetersPanel, 'numeric');
            app.Upperdepth.Value = -2000;
            app.LowerdepthmaslLabel = uilabel(app.IfYesfillthefieldsbelowinmetersPanel);
            app.LowerdepthmaslLabel.Text = 'Lower depth [m a.s.l.]';
            app.Lowerdepth = uieditfield(app.IfYesfillthefieldsbelowinmetersPanel, 'numeric');
            app.IfnoLowerdepthisselected = uilabel(app.IfYesfillthefieldsbelowinmetersPanel);
            app.IfnoLowerdepthisselected.Text = {'If  Lower depth is not selected the code will'; 'use the lower one '};
            app.RUN3Button = uibutton(app.GeologicalandthermalmodelsTab, 'state');
            app.RUN3Button.ValueChangedFcn = createCallbackFcn(app, @RUN3CutDepth, true);
            app.RUN3Button.Text = {'RUN'; '(3.)'};
            app.PetrophysicalpropertiesandHIPdistributionButton = uibutton(app.GeologicalandthermalmodelsTab, 'push');
            app.PetrophysicalpropertiesandHIPdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_LITO_DATA, true);
            app.PetrophysicalpropertiesandHIPdistributionButton.Text = {'Petrophysical'; 'properties and HIP'; 'distribution'; ''};
            app.Image3 = uiimage(app.GeologicalandthermalmodelsTab);
            app.Image3.ImageSource = 'Mini-Logo_3DHIP.png';
            app.GeothermalreservoirmapsandThistogramTab = uitab(app.TabGroup);
            app.GeothermalreservoirmapsandThistogramTab.Title = 'Geothermal reservoir maps and T histogram';
            app.UIAxes_eight = uiaxes(app.GeothermalreservoirmapsandThistogramTab);
            title(app.UIAxes_eight, 'Top')
            xlabel(app.UIAxes_eight, 'X [m]')
            ylabel(app.UIAxes_eight, 'Y [m]')
            app.UIAxes_nine = uiaxes(app.GeothermalreservoirmapsandThistogramTab);
            title(app.UIAxes_nine, 'Base')
            xlabel(app.UIAxes_nine, 'X [m]')
            ylabel(app.UIAxes_nine, 'Y [m]')
            app.UIAxes_ten = uiaxes(app.GeothermalreservoirmapsandThistogramTab);
            title(app.UIAxes_ten, 'Vertical thickness')
            xlabel(app.UIAxes_ten, 'X [m]')
            ylabel(app.UIAxes_ten, 'Y [m]')
            app.UIAxes_ten_bis = uiaxes(app.GeothermalreservoirmapsandThistogramTab);
            title(app.UIAxes_ten_bis, 'Temperature distribution')
            xlabel(app.UIAxes_ten_bis, 'Temperature [ºC]')
            ylabel(app.UIAxes_ten_bis, 'Frequency')
            app.Image3_4 = uiimage(app.GeothermalreservoirmapsandThistogramTab);
            app.Image3_4.ImageSource = 'Mini-Logo_3DHIP.png';
            app.Label_16 = uilabel(app.GeothermalreservoirmapsandThistogramTab);
            app.Label_16.Text = 'Geometry results plots: Top, Base, Vertical thickness and Temperature distribution for the selected target and depth range ';
            app.PetrophysicalpropertiesandHIPdistributionTab = uitab(app.TabGroup);
            app.PetrophysicalpropertiesandHIPdistributionTab.Title = 'Petrophysical properties and HIP distribution';
            app.UIAxes_two = uiaxes(app.PetrophysicalpropertiesandHIPdistributionTab);
            title(app.UIAxes_two, 'HIP Histogram')
            xlabel(app.UIAxes_two, 'HIP [PJ]')
            ylabel(app.UIAxes_two, 'Frequency')
            app.UIAxes_three = uiaxes(app.PetrophysicalpropertiesandHIPdistributionTab);
            title(app.UIAxes_three, 'Cumulative Probability curve')
            xlabel(app.UIAxes_three, 'HIP [PJ]')
            ylabel(app.UIAxes_three, 'Cumulative Probability [%]')
            app.PetrophysicalpropertiesandotherparametersPanel = uipanel(app.PetrophysicalpropertiesandHIPdistributionTab);
            app.PetrophysicalpropertiesandotherparametersPanel.Title = '4. Petrophysical properties and other parameters';
            app.ReinjectionTemperatureCLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.ReinjectionTemperatureCLabel.Text = {'Reinjection'; 'Temperature [ºC]'};
            app.ReinjectionTemp = uieditfield(app.PetrophysicalpropertiesandotherparametersPanel, 'numeric');
            app.ReinjectionTemp.ValueDisplayFormat = '%.1f';
            app.ReinjectionTemp.Value = 30;
            app.PorosityLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.PorosityLabel.Text = 'Porosity';
            app.PDFNormaldistributionLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.PDFNormaldistributionLabel.Text = 'PDF: Normal distribution';
            app.PDFTriangulardistributionLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.PDFTriangulardistributionLabel.Text = 'PDF: Triangular distribution';
            app.Panel_3 = uipanel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.MeanLabel = uilabel(app.Panel_3);
            app.MeanLabel.Text = 'Mean';
            app.Mean_Porosity = uieditfield(app.Panel_3, 'numeric');
            app.Mean_Porosity.ValueDisplayFormat = '%.3f';
            app.Mean_Porosity.Value = 0.2;
            app.StdLabel = uilabel(app.Panel_3);
            app.StdLabel.Text = 'Std';
            app.Std_Porosity = uieditfield(app.Panel_3, 'numeric');
            app.Std_Porosity.ValueDisplayFormat = '%.3f';
            app.Std_Porosity.Value = 0.01;
            app.MeanLabel_2 = uilabel(app.Panel_3);
            app.MeanLabel_2.Text = 'Mean';
            app.Mean_Fluid_Density = uieditfield(app.Panel_3, 'numeric');
            app.Mean_Fluid_Density.ValueDisplayFormat = '%.3f';
            app.Mean_Fluid_Density.Value = 1020;
            app.StdLabel_2 = uilabel(app.Panel_3);
            app.StdLabel_2.Text = 'Std';
            app.Std_Fluid_Density = uieditfield(app.Panel_3, 'numeric');
            app.Std_Fluid_Density.ValueDisplayFormat = '%.3f';
            app.Std_Fluid_Density.Value = 5;
            app.MeanLabel_3 = uilabel(app.Panel_3);
            app.MeanLabel_3.Text = 'Mean';
            app.Mean_Fluidvolumetricheatcapacity = uieditfield(app.Panel_3, 'numeric');
            app.Mean_Fluidvolumetricheatcapacity.ValueDisplayFormat = '%.3f';
            app.Mean_Fluidvolumetricheatcapacity.Value = 4.8;
            app.StdLabel_3 = uilabel(app.Panel_3);
            app.StdLabel_3.Text = 'Std';
            app.Std_Fluidvolumetricheatcapacity = uieditfield(app.Panel_3, 'numeric');
            app.Std_Fluidvolumetricheatcapacity.ValueDisplayFormat = '%.3f';
            app.Std_Fluidvolumetricheatcapacity.Value = 0.1;
            app.MeanLabel_4 = uilabel(app.Panel_3);
            app.MeanLabel_4.Text = 'Mean';
            app.Mean_Litovolumetricheatcapacity = uieditfield(app.Panel_3, 'numeric');
            app.Mean_Litovolumetricheatcapacity.ValueDisplayFormat = '%.3f';
            app.Mean_Litovolumetricheatcapacity.Value = 0.9;
            app.StdLabel_4 = uilabel(app.Panel_3);
            app.StdLabel_4.Text = 'Std';
            app.Std_Litovolumetricheatcapacity = uieditfield(app.Panel_3, 'numeric');
            app.Std_Litovolumetricheatcapacity.ValueDisplayFormat = '%.3f';
            app.Std_Litovolumetricheatcapacity.Value = 0.01;
            app.Check_N_Poro = uicheckbox(app.Panel_3);
            app.Check_N_Poro.Text = '';
            app.Check_N_Poro.Value = true;
            app.Check_N_FDen = uicheckbox(app.Panel_3);
            app.Check_N_FDen.Text = '';
            app.Check_N_FDen.Value = true;
            app.Check_N_FVol = uicheckbox(app.Panel_3);
            app.Check_N_FVol.Text = '';
            app.Check_N_FVol.Value = true;
            app.Check_N_LVol = uicheckbox(app.Panel_3);
            app.Check_N_LVol.Text = '';
            app.Check_N_LVol.Value = true;
            app.StdLabel_6 = uilabel(app.Panel_3);
            app.StdLabel_6.Text = 'Std';
            app.Std_Lito_Density = uieditfield(app.Panel_3, 'numeric');
            app.Std_Lito_Density.ValueDisplayFormat = '%.3f';
            app.Std_Lito_Density.Value = 10;
            app.MeanLabel_6 = uilabel(app.Panel_3);
            app.MeanLabel_6.Text = 'Mean';
            app.Mean_Lito_Density = uieditfield(app.Panel_3, 'numeric');
            app.Mean_Lito_Density.ValueDisplayFormat = '%.3f';
            app.Mean_Lito_Density.Value = 2500;
            app.Check_N_LDen = uicheckbox(app.Panel_3);
            app.Check_N_LDen.Text = '';
            app.Check_N_LDen.Value = true;
            app.Panel_4 = uipanel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.LowEditFieldLabel = uilabel(app.Panel_4);
            app.LowEditFieldLabel.Text = 'Low';
            app.Low_Porosity = uieditfield(app.Panel_4, 'numeric');
            app.UppEditFieldLabel = uilabel(app.Panel_4);
            app.UppEditFieldLabel.Text = 'Upp';
            app.Upp_Porosity = uieditfield(app.Panel_4, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.Panel_4);
            app.MaxEditFieldLabel.Text = 'Max';
            app.Max_Porosity = uieditfield(app.Panel_4, 'numeric');
            app.UppEditField_2Label = uilabel(app.Panel_4);
            app.UppEditField_2Label.Text = 'Upp';
            app.Upp_Fluid_Density = uieditfield(app.Panel_4, 'numeric');
            app.MaxEditField_2Label = uilabel(app.Panel_4);
            app.MaxEditField_2Label.Text = 'Max';
            app.Max_Fluid_Density = uieditfield(app.Panel_4, 'numeric');
            app.LowEditField_2Label = uilabel(app.Panel_4);
            app.LowEditField_2Label.Text = 'Low';
            app.Low_Fluid_Density = uieditfield(app.Panel_4, 'numeric');
            app.MaxEditField_2Label_2 = uilabel(app.Panel_4);
            app.MaxEditField_2Label_2.Text = 'Max';
            app.Max_Fluid_Volumetric = uieditfield(app.Panel_4, 'numeric');
            app.UppEditField_2Label_2 = uilabel(app.Panel_4);
            app.UppEditField_2Label_2.Text = 'Upp';
            app.Upp_Fluid_Volumetric = uieditfield(app.Panel_4, 'numeric');
            app.LowEditField_2Label_2 = uilabel(app.Panel_4);
            app.LowEditField_2Label_2.Text = 'Low';
            app.Low_Fluid_Volumetric = uieditfield(app.Panel_4, 'numeric');
            app.MaxEditField_2Label_3 = uilabel(app.Panel_4);
            app.MaxEditField_2Label_3.Text = 'Max';
            app.Max_Lito_Volumetric = uieditfield(app.Panel_4, 'numeric');
            app.UppEditField_2Label_3 = uilabel(app.Panel_4);
            app.UppEditField_2Label_3.Text = 'Upp';
            app.Upp_Lito_Volumetric = uieditfield(app.Panel_4, 'numeric');
            app.LowEditField_2Label_3 = uilabel(app.Panel_4);
            app.LowEditField_2Label_3.Text = 'Low';
            app.Low_Lito_Volumetric = uieditfield(app.Panel_4, 'numeric');
            app.Check_T_LVol = uicheckbox(app.Panel_4);
            app.Check_T_LVol.Text = '';
            app.Check_T_FVol = uicheckbox(app.Panel_4);
            app.Check_T_FVol.Text = '';
            app.Check_T_FDen = uicheckbox(app.Panel_4);
            app.Check_T_FDen.Text = '';
            app.Check_T_Poro = uicheckbox(app.Panel_4);
            app.Check_T_Poro.Text = '';
            app.Check_T_LDen = uicheckbox(app.Panel_4);
            app.Check_T_LDen.Text = '';
            app.LowEditField_2Label_5 = uilabel(app.Panel_4);
            app.LowEditField_2Label_5.Text = 'Low';
            app.Low_Lito_Density = uieditfield(app.Panel_4, 'numeric');
            app.UppEditField_2Label_5 = uilabel(app.Panel_4);
            app.UppEditField_2Label_5.Text = 'Upp';
            app.Upp_Lito_Density = uieditfield(app.Panel_4, 'numeric');
            app.MaxEditField_2Label_5 = uilabel(app.Panel_4);
            app.MaxEditField_2Label_5.Text = 'Max';
            app.Max_Lito_Density = uieditfield(app.Panel_4, 'numeric');
            app.FluidDensitykgm3Label = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.FluidDensitykgm3Label.Text = {'Fluid Density'; '[kg/m3]'};
            app.FluidspecificheatcapacitykJkgCLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.FluidspecificheatcapacitykJkgCLabel.Text = {'Fluid specific'; 'heat capacity'; '[kJ/kg ºC]'};
            app.RockspecificheatcapacitykJkgCLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.RockspecificheatcapacitykJkgCLabel.Text = {'Rock specific'; 'heat capacity'; '[kJ/kg ºC]'};
            app.Poro_no_used = uieditfield(app.PetrophysicalpropertiesandotherparametersPanel, 'text');
            app.Poro_no_used.Visible = 'off';
            app.PlantFactorLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.PlantFactorLabel.Text = {'Plant'; 'Factor'};
            app.PlantFactor = uieditfield(app.PetrophysicalpropertiesandotherparametersPanel, 'numeric');
            app.PlantFactor.ValueDisplayFormat = '%.2f';
            app.PlantFactor.Value = 0.95;
            app.RecoveryFactorLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.RecoveryFactorLabel.Text = 'Recovery Factor';
            app.Panel_6 = uipanel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.Check_N_RF = uicheckbox(app.Panel_6);
            app.Check_N_RF.Text = '';
            app.Check_N_RF.Value = true;
            app.StdLabel_5 = uilabel(app.Panel_6);
            app.StdLabel_5.Text = 'Std';
            app.Std_RF = uieditfield(app.Panel_6, 'numeric');
            app.Std_RF.ValueDisplayFormat = '%.3f';
            app.Std_RF.Value = 0.01;
            app.MeanLabel_5 = uilabel(app.Panel_6);
            app.MeanLabel_5.Text = 'Mean';
            app.Mean_RF = uieditfield(app.Panel_6, 'numeric');
            app.Mean_RF.ValueDisplayFormat = '%.3f';
            app.Mean_RF.Value = 0.1;
            app.Panel_7 = uipanel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.Check_T_RF = uicheckbox(app.Panel_7);
            app.Check_T_RF.Text = '';
            app.LowEditField_2Label_4 = uilabel(app.Panel_7);
            app.LowEditField_2Label_4.Text = 'Low';
            app.Low_RF = uieditfield(app.Panel_7, 'numeric');
            app.MaxEditField_2Label_4 = uilabel(app.Panel_7);
            app.MaxEditField_2Label_4.Text = 'Max';
            app.Max_RF = uieditfield(app.Panel_7, 'numeric');
            app.UppEditField_2Label_4 = uilabel(app.Panel_7);
            app.UppEditField_2Label_4.Text = 'Upp';
            app.Upp_RF = uieditfield(app.Panel_7, 'numeric');
            app.MeanPlantLivetimeyearsLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.MeanPlantLivetimeyearsLabel.Text = {'Mean Plant'; 'Livetime [years]'};
            app.Live_Time = uieditfield(app.PetrophysicalpropertiesandotherparametersPanel, 'numeric');
            app.Live_Time.ValueDisplayFormat = '%.1f';
            app.Live_Time.Value = 30;
            app.RockDensitykgm3Label = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.RockDensitykgm3Label.Text = {'Rock Density'; '[kg/m3]'};
            app.Den_no_used = uieditfield(app.PetrophysicalpropertiesandotherparametersPanel, 'text');
            app.Den_no_used.Visible = 'off';
            app.ConversionEfficiencyEditFieldLabel = uilabel(app.PetrophysicalpropertiesandotherparametersPanel);
            app.ConversionEfficiencyEditFieldLabel.Text = {'Conversion'; 'Efficiency'};
            app.ConversionEfficiency = uieditfield(app.PetrophysicalpropertiesandotherparametersPanel, 'numeric');
            app.ConversionEfficiency.Value = 0.85;
            app.RUN4Button = uibutton(app.PetrophysicalpropertiesandHIPdistributionTab, 'state');
            app.RUN4Button.ValueChangedFcn = createCallbackFcn(app, @RUN45HIP, true);
            app.RUN4Button.Text = {'RUN'; '(4.)'};
            app.HeatInPlaceresultsPanel = uipanel(app.PetrophysicalpropertiesandHIPdistributionTab);
            app.HeatInPlaceresultsPanel.Title = 'Heat-In-Place results';
            app.GeothermaltargetEditFieldLabel = uilabel(app.HeatInPlaceresultsPanel);
            app.GeothermaltargetEditFieldLabel.Text = 'Geothermal target';
            app.GeothermaltargetEditField = uieditfield(app.HeatInPlaceresultsPanel, 'text');
            app.Panel_2 = uipanel(app.HeatInPlaceresultsPanel);
            app.P90PJLabel = uilabel(app.Panel_2);
            app.P90PJLabel.Text = 'P90 [PJ]';
            app.P90PJ = uieditfield(app.Panel_2, 'numeric');
            app.P50PJLabel = uilabel(app.Panel_2);
            app.P50PJLabel.Text = 'P50 [PJ]';
            app.P50PJ = uieditfield(app.Panel_2, 'numeric');
            app.P10PJLabel = uilabel(app.Panel_2);
            app.P10PJLabel.Text = 'P10 [PJ]';
            app.P10PJ = uieditfield(app.Panel_2, 'numeric');
            app.NumberofsimulationsEditFieldLabel = uilabel(app.Panel_2);
            app.NumberofsimulationsEditFieldLabel.Text = {'Number of'; 'simulations'};
            app.NumberofsimulationsEditField = uieditfield(app.Panel_2, 'numeric');
            app.HIPandTmapsButton = uibutton(app.PetrophysicalpropertiesandHIPdistributionTab, 'push');
            app.HIPandTmapsButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_RESULTS_VIE, true);
            app.HIPandTmapsButton.Text = {'HIP and T'; 'maps'};
            app.GeothermalreservoirmapsamdThistogramButton = uibutton(app.PetrophysicalpropertiesandHIPdistributionTab, 'push');
            app.GeothermalreservoirmapsamdThistogramButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_GEOMETRY, true);
            app.GeothermalreservoirmapsamdThistogramButton.Text = {'Geothermal reservoir'; 'maps amd T '; 'histogram'};
            app.ExportresultsButton = uibutton(app.PetrophysicalpropertiesandHIPdistributionTab, 'push');
            app.ExportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_EXPORT_DATA, true);
            app.ExportresultsButton.Text = 'Export results';
            app.P90PJEditFieldLabel = uilabel(app.PetrophysicalpropertiesandHIPdistributionTab);
            app.P90PJEditFieldLabel.Text = 'P90 [PJ]';
            app.P90PJEditField = uieditfield(app.PetrophysicalpropertiesandHIPdistributionTab, 'numeric');
            app.P50PJEditFieldLabel = uilabel(app.PetrophysicalpropertiesandHIPdistributionTab);
            app.P50PJEditFieldLabel.Text = 'P50 [PJ]';
            app.P50PJEditField = uieditfield(app.PetrophysicalpropertiesandHIPdistributionTab, 'numeric');
            app.P10PJEditFieldLabel = uilabel(app.PetrophysicalpropertiesandHIPdistributionTab);
            app.P10PJEditFieldLabel.Text = 'P10 [PJ]';
            app.P10PJEditField = uieditfield(app.PetrophysicalpropertiesandHIPdistributionTab, 'numeric');
            app.HRECButton = uibutton(app.PetrophysicalpropertiesandHIPdistributionTab, 'push');
            app.HRECButton.ButtonPushedFcn = createCallbackFcn(app, @P_B_Hrec, true);
            app.HRECButton.Text = 'Hrec maps';
            app.Image3_2 = uiimage(app.PetrophysicalpropertiesandHIPdistributionTab);
            app.Image3_2.ImageSource = 'Mini-Logo_3DHIP.png';
            app.HIPandTmapsTab = uitab(app.TabGroup);
            app.HIPandTmapsTab.Title = 'HIP and T maps';
            app.UIAxes_four = uiaxes(app.HIPandTmapsTab);
            title(app.UIAxes_four, 'HIP P10')
            xlabel(app.UIAxes_four, 'X [m]')
            ylabel(app.UIAxes_four, 'Y [m]')
            app.UIAxes_five = uiaxes(app.HIPandTmapsTab);
            title(app.UIAxes_five, 'HIP P90')
            xlabel(app.UIAxes_five, 'X [m]')
            ylabel(app.UIAxes_five, 'Y [m]')
            app.UIAxes_six = uiaxes(app.HIPandTmapsTab);
            title(app.UIAxes_six, 'HIP P50')
            xlabel(app.UIAxes_six, 'X [m]')
            ylabel(app.UIAxes_six, 'Y [m]')
            app.UIAxes_seven = uiaxes(app.HIPandTmapsTab);
            title(app.UIAxes_seven, 'Top temperature')
            xlabel(app.UIAxes_seven, 'X [m]')
            ylabel(app.UIAxes_seven, 'Y [m]')
            app.Image3_3 = uiimage(app.HIPandTmapsTab);
            app.Image3_3.ImageSource = 'Mini-Logo_3DHIP.png';
            app.Label_15 = uilabel(app.HIPandTmapsTab);
            app.Label_15.Text = 'Heat-In-Place results plots: HIP P10, HIP P50, HIP P90 and Temperature at the top for the selected target and depth range ';
            app.HrecmapsTab = uitab(app.TabGroup);
            app.HrecmapsTab.Title = 'Hrec maps';
            app.UIAxes_eleven = uiaxes(app.HrecmapsTab);
            title(app.UIAxes_eleven, ' Hrec P10')
            xlabel(app.UIAxes_eleven, 'X [m]')
            ylabel(app.UIAxes_eleven, 'Y [m]')
            app.UIAxes_twelve = uiaxes(app.HrecmapsTab);
            title(app.UIAxes_twelve, 'Hrec P50')
            xlabel(app.UIAxes_twelve, 'X [m]')
            ylabel(app.UIAxes_twelve, 'Y [m]')
            app.UIAxes_thirteen = uiaxes(app.HrecmapsTab);
            title(app.UIAxes_thirteen, 'Hrec P90')
            xlabel(app.UIAxes_thirteen, 'X [m]')
            ylabel(app.UIAxes_thirteen, 'Y [m]')
            app.Image3_5 = uiimage(app.HrecmapsTab);
            app.Image3_5.ImageSource = 'Mini-Logo_3DHIP.png';
            app.Label_17 = uilabel(app.HrecmapsTab);
            app.Label_17.Text = 'Recoverable Heat Energy results plots: Hrec P10, Hrec P50 and Hrec P90 for the selected target and depth range ';
            app.ExportresultsTab = uitab(app.TabGroup);
            app.ExportresultsTab.Title = 'Export results';
            app.ExportfilesPanel = uipanel(app.ExportresultsTab);
            app.ExportfilesPanel.Title = '5. Export files';
            app.File13DModelPanel = uipanel(app.ExportfilesPanel);
            app.File13DModelPanel.Title = '  File 1 (3D Model):';
            app.DirectoryEditField_2Label = uilabel(app.File13DModelPanel);
            app.DirectoryEditField_2Label.Text = 'Directory';
            app.DirectoryFile1 = uieditfield(app.File13DModelPanel, 'text');
            app.BROWSEFile1 = uibutton(app.File13DModelPanel, 'push');
            app.BROWSEFile1.ButtonPushedFcn = createCallbackFcn(app, @ExpFile1, true);
            app.BROWSEFile1.Text = 'BROWSE';
            app.FilenameLabel_2 = uilabel(app.File13DModelPanel);
            app.FilenameLabel_2.Text = 'File name';
            app.NAMEFile1 = uieditfield(app.File13DModelPanel, 'text');
            app.FILE1XmYmZmP10PJP50PJP90PJMEAN_HIPPJSTD_HIPPJTCStd_TCLabel_2 = uilabel(app.File13DModelPanel);
            app.FILE1XmYmZmP10PJP50PJP90PJMEAN_HIPPJSTD_HIPPJTCStd_TCLabel_2.Text = {' - X [m]'; ' - Y [m]'; ' - Z [m]  '; ' - P10_HIP [PJ] '; ' - P50_HIP [PJ] '; ' - P90_HIP [PJ] '; ' - MEAN_HIP [PJ]'; ' - STD_HIP [PJ]'; ' - T [ºC]'; ' - Std_T [ºC]'; ' - GEO '; ' - DENSITY (defined in the geological model) [g/cm3]'; ' - POROSITY (defined in the geological model)'; ' - P10_Hrec [kW]'; ' - P50_Hrec [kW]'; ' - P90_Hrec [kW] '};
            app.Exportedvariables3DmodelLabel = uilabel(app.File13DModelPanel);
            app.Exportedvariables3DmodelLabel.Text = 'Exported variables (3D model)';
            app.ReportPanel = uipanel(app.ExportfilesPanel);
            app.ReportPanel.Title = '  Report:';
            app.DirectoryEditFieldLabel_2 = uilabel(app.ReportPanel);
            app.DirectoryEditFieldLabel_2.Text = 'Directory';
            app.DirectoryReport = uieditfield(app.ReportPanel, 'text');
            app.FilenameLabel_3 = uilabel(app.ReportPanel);
            app.FilenameLabel_3.Text = 'File name';
            app.NAMEReport = uieditfield(app.ReportPanel, 'text');
            app.BROWSEReport = uibutton(app.ReportPanel, 'push');
            app.BROWSEReport.ButtonPushedFcn = createCallbackFcn(app, @ExpReport, true);
            app.BROWSEReport.Text = 'BROWSE';
            app.RUN5Button = uibutton(app.ExportfilesPanel, 'push');
            app.RUN5Button.ButtonPushedFcn = createCallbackFcn(app, @RUN5ExpDat, true);
            app.RUN5Button.Text = {'RUN'; '(5.)'};
            app.File22DModelPanel = uipanel(app.ExportfilesPanel);
            app.File22DModelPanel.Title = '  File 2 (2D Model):';
            app.DirectoryEditFieldLabel = uilabel(app.File22DModelPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryFile2 = uieditfield(app.File22DModelPanel, 'text');
            app.BROWSEFile2 = uibutton(app.File22DModelPanel, 'push');
            app.BROWSEFile2.ButtonPushedFcn = createCallbackFcn(app, @ExpFile2, true);
            app.BROWSEFile2.Text = 'BROWSE';
            app.FilenameLabel = uilabel(app.File22DModelPanel);
            app.FilenameLabel.Text = 'File name';
            app.NAMEFile2 = uieditfield(app.File22DModelPanel, 'text');
            app.FILE2XmYmP10PJP50PJP90PJFOR2DVISUALIZATIONLabel_2 = uilabel(app.File22DModelPanel);
            app.FILE2XmYmP10PJP50PJP90PJFOR2DVISUALIZATIONLabel_2.Text = {'- X [m] '; '- Y [m] '; '- P10_HIP [PJ]'; '- P50_HIP [PJ] '; '- P90_HIP [PJ]'; '- P10_Hrec [kW]'; '- P50_Hrec [kW]'; '- P90_Hrec [kW] '};
            app.Exportedvariables2DmodelLabel = uilabel(app.File22DModelPanel);
            app.Exportedvariables2DmodelLabel.Text = 'Exported variables (2D model)';
            app.Image6 = uiimage(app.ExportresultsTab);
            app.Image6.ImageSource = 'Mini-Logo_3DHIP.png';
            app.Version12August2022Label_2 = uilabel(app.ExportresultsTab);
            app.Version12August2022Label_2.Text = 'Version 1.2 (August 2022)';
            app.PirisGHermsIGrieraAGomezRivasEColomerMLabel = uilabel(app.ExportresultsTab);
            app.PirisGHermsIGrieraAGomezRivasEColomerMLabel.Text = 'Piris, G., Herms, I., Griera, A., Gomez-Rivas, E. & Colomer, M.';
            app.Image9 = uiimage(app.ExportresultsTab);
            app.Image9.ImageSource = 'logoICGC.svg';
            app.Image10 = uiimage(app.ExportresultsTab);
            app.Image10.ImageSource = 'logoUAB.svg';
            % Show the figure after all components are created
            app.HIP3D_Calculator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HIP_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HIP3D_Calculator)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HIP3D_Calculator)
        end
    end
end